classdef AeroToolsV1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SiaASubOrbitalsSoftwareSuiteV10UIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewVehicleMenu                  matlab.ui.container.Menu
        NewVehicleComponentMenu         matlab.ui.container.Menu
        ExportSessiontoExcelMenu        matlab.ui.container.Menu
        SiaASubOrbitalsAerodynamicsTandemFlightSoftwareSuiteLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        VehicleEditorTab                matlab.ui.container.Tab
        Tree                            matlab.ui.container.Tree
        Panel_2                         matlab.ui.container.Panel
        FlightConditionTab              matlab.ui.container.Tab
        NameEditFieldLabel              matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        FlightConditionInformationLabel  matlab.ui.control.Label
        AltitudeEditFieldLabel          matlab.ui.control.Label
        AltitudeEditField               matlab.ui.control.NumericEditField
        AirSpeedLabel                   matlab.ui.control.Label
        SpeedDropDownLabel              matlab.ui.control.Label
        SpeedDropDown                   matlab.ui.control.DropDown
        MachEditField_2Label            matlab.ui.control.Label
        MachEditField_2                 matlab.ui.control.NumericEditField
        VelocityEditFieldLabel          matlab.ui.control.Label
        VelocityEditField               matlab.ui.control.NumericEditField
        qEditFieldLabel                 matlab.ui.control.Label
        qEditField                      matlab.ui.control.NumericEditField
        UnitsDropDownLabel              matlab.ui.control.Label
        UnitsDropDown                   matlab.ui.control.DropDown
        AtmosphericPropertiesPanel      matlab.ui.container.Panel
        AoAEditFieldLabel               matlab.ui.control.Label
        AoAEditField                    matlab.ui.control.NumericEditField
        NACAAirfoilTab                  matlab.ui.container.Tab
        NACAFourDigitAirfoilGeneratorLabel  matlab.ui.control.Label
        AirfoilPropertiesPanel          matlab.ui.container.Panel
        CamberEditFieldLabel            matlab.ui.control.Label
        CamberEditField                 matlab.ui.control.NumericEditField
        CamberLocationEditFieldLabel    matlab.ui.control.Label
        CamberLocationEditField         matlab.ui.control.NumericEditField
        MaxThicknessEditFieldLabel      matlab.ui.control.Label
        MaxThicknessEditField           matlab.ui.control.NumericEditField
        UIAxes3                         matlab.ui.control.UIAxes
        GenerateAirfoilButton           matlab.ui.control.Button
        NACANumberEditFieldLabel        matlab.ui.control.Label
        NACANumberEditField             matlab.ui.control.EditField
        ChordEditFieldLabel             matlab.ui.control.Label
        ChordEditField                  matlab.ui.control.NumericEditField
        SendtoExternalFigureButton      matlab.ui.control.StateButton
        SectionLiftTab                  matlab.ui.container.Tab
        ThinAirfoilTheoryCalculatorLabel  matlab.ui.control.Label
        SectionPanel                    matlab.ui.container.Panel
        CamberedCheckBox                matlab.ui.control.CheckBox
        ImportAirfoilButton             matlab.ui.control.Button
        AirfoilPropertiesTextAreaLabel  matlab.ui.control.Label
        AirfoilPropertiesTextArea       matlab.ui.control.TextArea
        UIAxes                          matlab.ui.control.UIAxes
        CombinedCorrectionsPanel        matlab.ui.container.Panel
        CompressibilityCheckBox         matlab.ui.control.CheckBox
        MachEditFieldLabel              matlab.ui.control.Label
        MachEditField                   matlab.ui.control.NumericEditField
        FiniteWingCheckBox              matlab.ui.control.CheckBox
        AREditFieldLabel                matlab.ui.control.Label
        AREditField                     matlab.ui.control.NumericEditField
        SweepAngleCheckBox              matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        SweepValue                      matlab.ui.control.NumericEditField
        Image2                          matlab.ui.control.Image
        ThinAirfoilTheoryPanel          matlab.ui.container.Panel
        CalculateSectionLiftButton      matlab.ui.control.Button
        AngleofAttackdegreesLabel       matlab.ui.control.Label
        FromEditFieldLabel              matlab.ui.control.Label
        FromEditField                   matlab.ui.control.NumericEditField
        ToEditFieldLabel                matlab.ui.control.Label
        ToEditField                     matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        Image3                          matlab.ui.control.Image
        lLabel                          matlab.ui.control.Label
        CalculateZeroLiftAoAButton      matlab.ui.control.Button
        Image4                          matlab.ui.control.Image
        Panel                           matlab.ui.container.Panel
        Image5                          matlab.ui.control.Image
        CalculateMomentCoefficientButton  matlab.ui.control.Button
        CmLabel                         matlab.ui.control.Label
        VehicleLiftDragTab              matlab.ui.container.Tab
        LiftDragOutputTextAreaLabel     matlab.ui.control.Label
        LiftDragOutputTextArea          matlab.ui.control.TextArea
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = public)
        Airfoil % Description
        Session % Description
        AddVehicle % Vehicle Adding dialog box app
        AddVehicleComponentDialog % Vehicle Component Adding dialog box app
        FlightCond FlightCondition % Description
        AeroResults % Description
    end
    
    methods (Access = public)
        
        function obj = grab_class(app,name,parent) %% Returns the corresponding stored class when input a uitext element
            veh_list = GetVehicleList(app);
            if nargin == 3
                IsVehicle = IsInList(veh_list,parent);
                if IsVehicle == 1
                    plane = FindVehicle(app,parent);
                    
                    children = plane.GetChildren();
                    name_list = cell(length(children),1);
                    for i=1:length(children)
                        name_list{i,1} = children{i}.Name;
                    end
                    IsComponent = IsInList(name_list,name);
                    if IsComponent == 1
                        [~,obj] = plane.FindFirstChild(name);
                        return
                    else
                        uialert(app.SiaASubOrbitalsSoftwareSuiteV10UIFigure,strcat("Component name input is not apart of vehicle ",parent),"Internal Exception No. 69")
                    end
                else
                    uialert(app.SiaASubOrbitalsSoftwareSuiteV10UIFigure,'Vehicle Name Input is not apart of the session data','Internal Exception No. 70')
                    return
                end
            elseif nargin == 2
                IsVehicle = IsInList(veh_list,name);
                if IsVehicle == 1
                    obj = FindVehicle(app,name);
                    return
                else
                    uialert(app.SiaASubOrbitalsSoftwareSuiteV10UIFigure,'Vehicle Name Input is not apart of the session data','Internal Exception No. 71')
                    return
                end
            end
            
        end
        
        function NewVehicle(app, name)
            newvehicle = Vehicle;
            newvehicle.Name = name;
            newvehicle.Components = struct;
            app.Session.(strcat(class(newvehicle),string(length(GetVehicleList(app))))) = newvehicle;
        end
        
        function AddVehicleComponent(app,vehicle,component)
            veh_list = GetVehicleList(app);
            IsVehicle = IsInList(veh_list,vehicle);
            if IsVehicle == 1
                vehicle = FindVehicle(app,vehicle);
                uniquecompsount = 0;
                if isa(component,'WingGeometry')
                    child = vehicle.GetChildren();
                    for i=1:length(child)
                        if isa(child{i},'WingGeometry')
                            uniquecompsount = uniquecompsount + 1;
                        end
                    end
                elseif isa(component,'FuselageGeometry')
                    child = vehicle.GetChildren();
                    for i=1:length(child)
                        if isa(child{i},'Fuselage')
                            uniquecompsount = uniquecompsount + 1;
                        end
                    end
                end
                f = fieldnames(app.Session);
                for i=1:length(f)
                    if strcmp(app.Session.(f{i}).Name,vehicle.Name)
                        vehicle.Components.(strcat(class(component),num2str(uniquecompsount))) = component;
                        app.Session.(f{i}) = vehicle;
                        return
                    end
                end
                uialert(app.SiaASubOrbitalsSoftwareSuiteV10UIFigure,'YOU DUN MESSED UP A-ARON','Oshaghennesy')
            else
                uialert(app.SiaASubOrbitalsSoftwareSuiteV10UIFigure,'Vehicle Name Input is not apart of the session data','Internal Exception No. 420')
                return
            end
        end
        
        function veh_list = GetVehicleList(app)
            veh_list = cell(1);
            f = fieldnames(app.Session);
            if isempty(f)
                veh_list = {};
                return
            end
            truenum = 1;
            for i=1:length(f)
                if isa(app.Session.(f{i}),'Vehicle')
                    veh_list{truenum,1} = app.Session.(f{i}).Name;
                    truenum = truenum + 1;
                end
            end
        end
        
        function obj = FindVehicle(app,name)
            f = fieldnames(app.Session);
            for i=1:length(f)
                if isa(app.Session.(f{i}),'Vehicle')
                    if strcmp(app.Session.(f{i}).Name,name) == 1
                        obj = app.Session.(f{i});
                        return
                    end
                end
            end
        end
        
%         function bool = IsInList(~,list,item)
%             for i=1:length(list)
%                 if isa(list,'cell')
%                     if strcmp(list{i},item)==1
%                         bool = 1;
%                         return
%                     end
%                 end
%             end
%             bool = 0;
%         end
        
        function bool = check_existence(app,name)
            veh_list = fieldnames(app.Session);
            
            for i=1:length(veh_list)
                if strcmp(veh_list{i},name)==1
                    bool = 1;
                    return
                else
                    component_list = app.Session.(veh_list{i}).Components;
                    for j=1:length(component_list)
                        if strcmp(component_list{j}.Name,name)
                            bool = 1;
                            return
                        end
                    end
                end
            end
            bool = 0;
        end
        
        function ReturnFoil(app,field,foil)
            app.OutputComponent.(field) = foil;
        end
        
        function val_changed(app,~,prop,element)
            app.OutputComponent.(prop) = element.Value;
        end
        
        function DispProp(~,var,gui,xoffset,editable,excludeables)
            delete(gui.Children())
            top_offset = 50;
            fieldsize = [100 22];
            inert = [gui.Position(3)/2 gui.Position(4)-top_offset];
            dy = 30;
            dx = 15;
            f = fieldnames(var);
            l = length(f);
            n = 1;
            for i=1:l
                if IsInList(excludeables,f{i})==0
                    if isa(var.(f{i}),'double')
                        element = uieditfield(gui,'numeric',"ValueChangedFcn",@(element,event) val_changed(app,event,f{i},element));
                        element.Parent = gui;
                        if isempty(var.(f{i}))==0
                            element.Value = var.(f{i});
                        end
                        element.Position = [inert(1)-xoffset inert(2)-((n-1)*dy) fieldsize(1) fieldsize(2)];
                        element.HorizontalAlignment = 'right';
                        label = uilabel(gui,"Text",f{i});
                        label.Parent = gui;
                        w = 150;
                        label.Position(3) = w;
                        label.Position(1:2) = [inert(1)-dx-w-xoffset inert(2)-((n-1)*dy)];
                        label.HorizontalAlignment = 'right';
                    elseif ischar(var.(f{i})) || isa(var.(f{i}),'string') || isa(var.(f{i}),'NACA4')
                        element = uieditfield(gui,"text","ValueChangedFcn",@(element,event) val_changed(app,event,f{i},element));
                        element.Parent = gui;
                        if isempty(var.(f{i}))==0
                            if isa(var.(f{i}),'NACA4')
                                element.Value = var.(f{i}).Name;
                                element.HorizontalAlignment = 'right';
                            elseif strcmp(f{i},'Units')
                                delete(element)
                                element = uidropdown(gui,'Items',{'SI','FPS'},'Value',var.(f{i}));
                                if isempty(var.(f{i}))==1
                                    element.Value = "SI";
                                end
                            else
                                element.Value = var.(f{i});
                                element.HorizontalAlignment = 'right';
                            end
                        end
                        element.Position = [inert(1)-xoffset inert(2)-((n-1)*dy) fieldsize(1) fieldsize(2)];
                        label = uilabel(gui,"Text",f{i});
                        label.Parent = gui;
                        w = 150;
                        label.Position(3) = w;
                        label.Position(1:2) = [inert(1)-dx-w-xoffset inert(2)-((n-1)*dy)];
                        label.HorizontalAlignment = 'right';
                    elseif strcmp(f{i},'Units')
                        element = uidropdown(gui,'Items',{'SI','FPS'},'Value',var.(f{i}),"ValueChangedFcn",@(element,event) val_changed(app,event,f{i},element));
                        element.Parent = gui;
                        if isempty(var.(f{i}))==0
                            element.Value = 'SI';
                        end
                        element.Position = [inert(1)-xoffset inert(2)-((n-1)*dy) fieldsize(1) fieldsize(2)];
                        element.HorizontalAlignment = 'right';
                        label = uilabel(gui,"Text",f{i});
                        label.Parent = gui;
                        w = 150;
                        label.Position(3) = w;
                        label.Position(1:2) = [inert(1)-dx-w-xoffset inert(2)-((n-1)*dy)];
                        label.HorizontalAlignment = 'right';
                    end
                    element.Editable = editable;
                    n = n + 1;
                end
            end
        end
        
        function UpdateTree(app)
            delete(app.Tree.Children())
            vehlist = GetVehicleList(app);
            for i=1:length(vehlist)
                veh = FindVehicle(app,vehlist{i});
                node = uitreenode(app.Tree,'Text',veh.Name);
                comp = veh.Components;
                children = veh.GetChildren();
                if isempty(children)==0
                    f = fieldnames(comp);
                    for j=1:length(f)
                        val = comp.(f{j});
                        uitreenode(node,'Text',val.Name)
                    end
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Session = struct();
            begin = FlightCondition;
            begin.Altitude = app.AltitudeEditField.Value; % Altitude in feet, condition for cruise is 35kft.
            begin.Units = app.UnitsDropDown.Value;
            begin.AoA = 0;
            begin = begin.SetSpeed('V',app.VelocityEditField.Value); % Takeoff velocity in ft/s
            begin.Name = app.NameEditField.Value;
            app.FlightCond = begin;
            app.AeroResults = AerodynamicResults;
            app.AeroResults.Name = "Placeholder";
            DispProp(app,app.FlightCond.Atmosphere,app.AtmosphericPropertiesPanel,20,0,["Name","Units","Parent"])
        end

        % Value changed function: CompressibilityCheckBox
        function CompressibilityCheckBoxValueChanged(app, event)
            value = app.CompressibilityCheckBox.Value;
            if value == 1
                app.MachEditField.Visible = 1;
                app.MachEditFieldLabel.Visible = 1;
            else
                app.MachEditField.Visible = 0;
                app.MachEditFieldLabel.Visible = 0;
            end
        end

        % Value changed function: FiniteWingCheckBox
        function FiniteWingCheckBoxValueChanged(app, event)
            value = app.FiniteWingCheckBox.Value;
            if value == 1
                app.AREditField.Visible = 1;
                app.AREditFieldLabel.Visible = 1;
            else
                app.AREditField.Visible = 0;
                app.AREditFieldLabel.Visible = 0;
            end
        end

        % Value changed function: SweepAngleCheckBox
        function SweepAngleCheckBoxValueChanged(app, event)
            value = app.SweepAngleCheckBox.Value;
            if value == 1
                app.SweepValue.Visible = 1;
                app.Label.Visible = 1;
            else
                app.SweepValue.Visible = 0;
                app.Label.Visible = 0;
            end
        end

        % Value changed function: CamberedCheckBox
        function CamberedCheckBoxValueChanged(app, event)
            value = app.CamberedCheckBox.Value;
            if value == 0
                app.ImportAirfoilButton.Visible = 0;
                app.AirfoilPropertiesTextArea.Visible = 0;
                app.AirfoilPropertiesTextAreaLabel.Visible = 0;
                app.UIAxes.Visible = 0;
                app.SectionPanel.Title = 'Section: Symmetric';
            elseif value == 1
                app.ImportAirfoilButton.Visible = 1;
                app.AirfoilPropertiesTextArea.Visible = 1;
                app.AirfoilPropertiesTextAreaLabel.Visible = 1;
                app.UIAxes.Visible = 1;
                app.SectionPanel.Title = 'Section: Cambered';
            end
        end

        % Button pushed function: GenerateAirfoilButton
        function GenerateAirfoilButtonPushed(app, event)
            if strcmp(app.NACANumberEditField.Value,'') == 1 || app.MaxThicknessEditField.Value == 0
                uialert(app.SiaASubOrbitalsAerodynamicsTandemFlightSoftwareSuiteLabel,'Please Input either a NACA number or Maximum thickness ratio','Internal Exception No. Boiling Sausages')
            end
            clear app.Airfoil
            app.Airfoil = NACA4WingSection(app.NACANumberEditField.Value,app.ChordEditField.Value,128);
            if app.SendtoExternalFigureButton.Value == 0
                app.UIAxes3.Visible = 1;
                app.Airfoil.PlotFoil(app.UIAxes3)
            elseif app.SendtoExternalFigureButton.Value == 1
                app.Airfoil.PlotFoil()
            end
        end

        % Value changed function: NACANumberEditField
        function NACANumberEditFieldValueChanged(app, event)
            value = app.NACANumberEditField.Value;
            [m,p,t] = NACA2Specs(value);
            app.CamberEditField.Value = m;
            app.CamberLocationEditField.Value = p;
            app.MaxThicknessEditField.Value = t;
        end

        % Value changed function: CamberEditField
        function CamberEditFieldValueChanged(app, event)
            m = app.CamberEditField.Value;
            NACA = Specs2NACA(m,app.CamberLocationEditField.Value,app.MaxThicknessEditField.Value);
            app.NACANumberEditField.Value = NACA;
        end

        % Value changed function: CamberLocationEditField
        function CamberLocationEditFieldValueChanged(app, event)
            p = app.CamberLocationEditField.Value;
            NACA = Specs2NACA(app.CamberEditField.Value,p,app.MaxThicknessEditField.Value);
            app.NACANumberEditField.Value = NACA;
        end

        % Value changed function: MaxThicknessEditField
        function MaxThicknessEditFieldValueChanged(app, event)
            t = app.MaxThicknessEditField.Value;
            NACA = Specs2NACA(app.CamberEditField.Value,app.CamberLocationEditField.Value,t);
            app.NACANumberEditField.Value = NACA;
        end

        % Button pushed function: ImportAirfoilButton
        function ImportAirfoilButtonPushed(app, event)
            ms = string(cellstr(strcat({'m = '},{' '},string(app.Airfoil.m))));
            ps = string(cellstr(strcat({'p = '},{' '},string(app.Airfoil.p))));
            ts = string(cellstr(strcat({'t = '},{' '},string(app.Airfoil.t))));
            cs = string(cellstr(strcat({'c = '},{' '},string(app.Airfoil.c))));
            naca = app.Airfoil.Name;
            prop = [ms;ps;ts;cs;naca];
            app.AirfoilPropertiesTextArea.Value = prop;
            app.AirfoilPropertiesPanel.Visible = 1;
            app.Airfoil.PlotFoil(app.UIAxes)
            app.UIAxes.Visible = 1;
        end

        % Button pushed function: CalculateZeroLiftAoAButton
        function CalculateZeroLiftAoAButtonPushed(app, event)
            c = app.Airfoil.c;
            theta = transpose(0:pi/(app.Airfoil.nseg-1):pi) ;
            %f = @app.Airfoil.MeanSlope;
            x = transpose(c/2*(1 - cos(theta))) ;
            dydx = zeros(length(x),1);
            
            for i = 1:length(x)
                dydx(i) = app.Airfoil.MeanSlope(x(i));
            end
            
            integrand = -1/pi * dydx .* (cos(theta) - 1) ;
            
            a0l = integrate_simpson(integrand,0,pi,128);
            
            app.Airfoil.a0l = a0l;
            
            app.lLabel.Text = string(cellstr(strcat({'a0l = '},{' '},string(a0l))));
        end

        % Button pushed function: CalculateMomentCoefficientButton
        function CalculateMomentCoefficientButtonPushed(app, event)
            c = app.Airfoil.c;
            theta = transpose(0:pi/(app.Airfoil.nseg-1):pi) ;
            %f = @app.Airfoil.MeanSlope;
            x = transpose(c/2*(1 - cos(theta))) ;
            dydx = zeros(length(x),1);
            
            for i = 1:length(x)
                dydx(i) = app.Airfoil.MeanSlope(x(i));
            end
            integrand = 1/2 * dydx .* (cos(2*theta) - cos(theta));

            CMac = integrate_simpson(integrand,0,pi,app.Airfoil.nseg);
            app.CmLabel.Text = string(cellstr(strcat({'Cmac = '},{' '},string(CMac))));
        end

        % Button pushed function: CalculateSectionLiftButton
        function CalculateSectionLiftButtonPushed(app, event)
            if app.CompressibilityCheckBox.Value == 1
                M0 = app.MachEditField.Value;
            else
                M0 = 0;
            end
            B = sqrt(1-M0^2);
            
            if app.FiniteWingCheckBox.Value == 1
                AR = app.AREditField.Value;
            else
                AR = 0;
            end
            
            if app.SweepAngleCheckBox.Value == 1
                delta = app.SweepValue.Value;
            else
                delta = 0;
            end

            m0 = 2*pi*AR/(2+sqrt(4+AR^2*B^2*(1+(tand(delta)^2/B^2)) ));
            
            AoA = app.FromEditField.Value:1:app.ToEditField.Value;
            CL = m0 * (deg2rad(AoA) - app.Airfoil.a0l);
            
            plot(app.UIAxes2,AoA,CL,'-b')
        end

        % Menu selected function: NewVehicleMenu
        function NewVehicleMenuSelected(app, event)
            app.AddVehicle = VehicleNameDialog(app);
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            
            if IsInList(GetVehicleList(app),selectedNodes.Text)==1
                class = grab_class(app,selectedNodes.Text);
            elseif IsInList(GetVehicleList(app),selectedNodes.Parent.Text)==1
                class = grab_class(app,selectedNodes.Text,selectedNodes.Parent.Text);
            end
            
            DispProp(app,class,app.Panel_2,30,1,["Components","Results"])
            app.Panel_2.Title = strcat(selectedNodes.Text,' Properties');
        end

        % Close request function: 
        % SiaASubOrbitalsSoftwareSuiteV10UIFigure
        function SiaASubOrbitalsSoftwareSuiteV10UIFigureCloseRequest(app, event)
            delete(app.AddVehicle)
            delete(app.AddVehicleComponentDialog)
            delete(app)
        end

        % Menu selected function: NewVehicleComponentMenu
        function NewVehicleComponentMenuSelected(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if isempty(selectedNodes)
                uialert(app.SiaASubOrbitalsSoftwareSuiteV10UIFigure,"You silly goose you have to select a vehicle first!",'rofl coptr')
                return
            end
            veh_name = selectedNodes.Text;
            veh_list = GetVehicleList(app);
            IsVehicle = IsInList(veh_list,veh_name);
            if IsVehicle == 1
                vehicle = FindVehicle(app,veh_name);
                app.AddVehicleComponentDialog = NewComponentDialog(app,vehicle);
            else
                uialert(app.SiaASubOrbitalsSoftwareSuiteV10UIFigure,'You must selected a vehicle to add a component to in order to continue.','Error')
                return
            end
        end

        % Value changed function: AltitudeEditField
        function AltitudeEditFieldValueChanged(app, event)
            value = app.AltitudeEditField.Value;
            app.FlightCond.Altitude = value;
            app.FlightCond = app.FlightCond.CalculateAtmosphericProperties();
            DispProp(app,app.FlightCond.Atmosphere,app.AtmosphericPropertiesPanel,20,0,["Name","Units"])
        end

        % Value changed function: SpeedDropDown
        function SpeedDropDownValueChanged(app, event)
            value = app.SpeedDropDown.Value;
            if strcmp(value,"Mach")==1
                app.VelocityEditField.Enable = 0;
                app.MachEditField_2.Enable = 1;
            else
                app.VelocityEditField.Enable = 1;
                app.MachEditField_2.Enable = 0;
            end
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            value = app.NameEditField.Value;
            app.FlightCond.Name = value;
            app.AeroResults.Name = value;
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            value = app.UnitsDropDown.Value;
            if strcmp(value,'SI')==1
                app.FlightCond = app.FlightCond.ConvertUnits("SI");
            elseif strcmp(value,'Imperial')==1
                app.FlightCond = app.FlightCond.ConvertUnits("FPS");
            end
            DispProp(app,app.FlightCond.Atmosphere,app.AtmosphericPropertiesPanel,20,0,["Name","Units"])
            app.MachEditField_2.Value = app.FlightCond.AirSpeed.Mach;
            app.VelocityEditField.Value = app.FlightCond.AirSpeed.V;
            app.qEditField.Value = app.FlightCond.AirSpeed.q;
        end

        % Value changed function: MachEditField_2
        function MachEditField_2ValueChanged(app, event)
            value = app.MachEditField_2.Value;
            app.FlightCond = app.FlightCond.SetSpeed("Mach",value);
            app.VelocityEditField.Value = app.FlightCond.AirSpeed.V;
            app.qEditField.Value = app.FlightCond.AirSpeed.q;
            DispProp(app,app.FlightCond.Atmosphere,app.AtmosphericPropertiesPanel,20,0,["Name","Units"])
        end

        % Value changed function: VelocityEditField
        function VelocityEditFieldValueChanged(app, event)
            value = app.VelocityEditField.Value;
            app.FlightCond = app.FlightCond.SetSpeed("V",value);
            app.MachEditField_2.Value = app.FlightCond.AirSpeed.Mach;
            app.qEditField.Value = app.FlightCond.AirSpeed.q;
            DispProp(app,app.FlightCond.Atmosphere,app.AtmosphericPropertiesPanel,20,0,["Name","Units"])
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            seltabval = selectedTab.Title;
            %file = fopen("text.txt",'w');
            if strcmp(seltabval,"Vehicle Lift/Drag")
                if strcmp(app.FlightCond.Units,'SI')
                    unit = "N";
                elseif strcmp(app.FlightCond.Units,'FPS')
                    unit = "lbf";
                end
                C = string;
                vehicle_list = GetVehicleList(app);
                total_vehicle_list = length(vehicle_list);
                n = 1;
                CDtot = 0;
                CLtot = 0;
                Ltot = 0;
                Dtot = 0;
                for i=1:total_vehicle_list
                    veh_name = vehicle_list{i};
                    active_vehicle = FindVehicle(app,string(veh_name));
                    C(n,1) = strcat(active_vehicle.Name,":");
                    n = n + 1;
                    comp_list = active_vehicle.GetChildren();
                    for c=1:length(comp_list)
                        if isa(comp_list{c},"WingGeometry")
                            [CD,CD0,CDL,D] = comp_list{c}.ReturnDrag(app.FlightCond);
                            [CL,CLa,L] = comp_list{c}.ReturnLift(app.FlightCond);
                            C(n,1) = strcat("     ",comp_list{c}.Name,": CD=",num2str(CD),", CD0=",num2str(CD0),", CDL=",num2str(CDL),", D=",num2str(D),", CL=",num2str(CL),", CLa=",num2str(CLa),", L=",num2str(L)," ",unit);
                        elseif isa(comp_list{c},"FuselageGeometry")
                            [CD,CD0,CDL,D] = comp_list{c}.ReturnDrag(app.FlightCond);
                            C(n,1) = strcat("     ",comp_list{c}.Name,": CD=",num2str(CD),", CD0=",num2str(CD0),", CDL=",num2str(CDL),", D=",num2str(D),unit);
                        end
                        CDtot = CDtot + CD;
                        CLtot = CLtot + CL;
                        Ltot = Ltot + L;
                        Dtot = Dtot + D;
                        n = n + 1;
                    end
                end
                app.AeroResults.CL = CLtot;
                app.AeroResults.CD = CDtot;
                app.AeroResults.L = Ltot;
                app.AeroResults.D = Dtot;
                app.AeroResults.AoA = app.FlightCond.AoA;
                app.AeroResults.Units = app.FlightCond.Units;
                C(n,1) = "";
                app.LiftDragOutputTextArea.Value = C;
            end
        end

        % Menu selected function: ExportSessiontoExcelMenu
        function ExportSessiontoExcelMenuSelected(app, event)
            VehicleList = GetVehicleList(app);
            
            for i=1:length(VehicleList)
                veh = FindVehicle(app,VehicleList{i});
                veh.Export2Excel(veh.Name)
                writecell(app.FlightCond.Data2Cell(),strcat(veh.Name,".xlsx"),"Sheet",app.FlightCond.Name)
                writecell(app.AeroResults.Data2Cell(),strcat(veh.Name,".xlsx"),"Sheet","Aerodynamic Results")
            end
            
        end

        % Value changed function: AoAEditField
        function AoAEditFieldValueChanged(app, event)
            app.FlightCond.AoA = app.AoAEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SiaASubOrbitalsSoftwareSuiteV10UIFigure = uifigure('Visible', 'off');
            app.SiaASubOrbitalsSoftwareSuiteV10UIFigure.Name = 'SiaA Sub-Orbitals Software Suite V1.0';
            app.SiaASubOrbitalsSoftwareSuiteV10UIFigure.CloseRequestFcn = createCallbackFcn(app, @SiaASubOrbitalsSoftwareSuiteV10UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.SiaASubOrbitalsSoftwareSuiteV10UIFigure);
            app.FileMenu.Text = 'File';
            app.NewVehicleMenu = uimenu(app.FileMenu);
            app.NewVehicleMenu.MenuSelectedFcn = createCallbackFcn(app, @NewVehicleMenuSelected, true);
            app.NewVehicleMenu.Text = 'New Vehicle';
            app.NewVehicleComponentMenu = uimenu(app.FileMenu);
            app.NewVehicleComponentMenu.MenuSelectedFcn = createCallbackFcn(app, @NewVehicleComponentMenuSelected, true);
            app.NewVehicleComponentMenu.Text = 'New Vehicle Component';
            app.ExportSessiontoExcelMenu = uimenu(app.FileMenu);
            app.ExportSessiontoExcelMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportSessiontoExcelMenuSelected, true);
            app.ExportSessiontoExcelMenu.Text = 'Export Session to Excel';
            app.SiaASubOrbitalsAerodynamicsTandemFlightSoftwareSuiteLabel = uilabel(app.SiaASubOrbitalsSoftwareSuiteV10UIFigure);
            app.SiaASubOrbitalsAerodynamicsTandemFlightSoftwareSuiteLabel.Text = 'SiaA Sub-Orbitals Aerodynamics Tandem Flight Software Suite';
            app.TabGroup = uitabgroup(app.SiaASubOrbitalsSoftwareSuiteV10UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.VehicleEditorTab = uitab(app.TabGroup);
            app.VehicleEditorTab.Title = 'Vehicle Editor';
            app.Tree = uitree(app.VehicleEditorTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Panel_2 = uipanel(app.VehicleEditorTab);
            app.FlightConditionTab = uitab(app.TabGroup);
            app.FlightConditionTab.Title = 'Flight Condition';
            app.NameEditFieldLabel = uilabel(app.FlightConditionTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.FlightConditionTab, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.NameEditField.Value = 'Takeoff';
            app.FlightConditionInformationLabel = uilabel(app.FlightConditionTab);
            app.FlightConditionInformationLabel.Text = 'Flight Condition Information';
            app.AltitudeEditFieldLabel = uilabel(app.FlightConditionTab);
            app.AltitudeEditFieldLabel.Text = 'Altitude';
            app.AltitudeEditField = uieditfield(app.FlightConditionTab, 'numeric');
            app.AltitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AltitudeEditFieldValueChanged, true);
            app.AirSpeedLabel = uilabel(app.FlightConditionTab);
            app.AirSpeedLabel.Text = 'Air Speed:';
            app.SpeedDropDownLabel = uilabel(app.FlightConditionTab);
            app.SpeedDropDownLabel.Text = 'Speed';
            app.SpeedDropDown = uidropdown(app.FlightConditionTab);
            app.SpeedDropDown.Items = {'Mach', 'Velocity', ''};
            app.SpeedDropDown.ValueChangedFcn = createCallbackFcn(app, @SpeedDropDownValueChanged, true);
            app.SpeedDropDown.Value = 'Mach';
            app.MachEditField_2Label = uilabel(app.FlightConditionTab);
            app.MachEditField_2Label.Text = 'Mach';
            app.MachEditField_2 = uieditfield(app.FlightConditionTab, 'numeric');
            app.MachEditField_2.ValueChangedFcn = createCallbackFcn(app, @MachEditField_2ValueChanged, true);
            app.MachEditField_2.Value = 0.172;
            app.VelocityEditFieldLabel = uilabel(app.FlightConditionTab);
            app.VelocityEditFieldLabel.Text = 'Velocity';
            app.VelocityEditField = uieditfield(app.FlightConditionTab, 'numeric');
            app.VelocityEditField.ValueChangedFcn = createCallbackFcn(app, @VelocityEditFieldValueChanged, true);
            app.VelocityEditField.Enable = 'off';
            app.qEditFieldLabel = uilabel(app.FlightConditionTab);
            app.qEditFieldLabel.Text = 'q';
            app.qEditField = uieditfield(app.FlightConditionTab, 'numeric');
            app.qEditField.Editable = 'off';
            app.qEditField.Enable = 'off';
            app.UnitsDropDownLabel = uilabel(app.FlightConditionTab);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.FlightConditionTab);
            app.UnitsDropDown.Items = {'SI', 'Imperial', ''};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'SI';
            app.AtmosphericPropertiesPanel = uipanel(app.FlightConditionTab);
            app.AtmosphericPropertiesPanel.Title = 'Atmospheric Properties:';
            app.AoAEditFieldLabel = uilabel(app.FlightConditionTab);
            app.AoAEditFieldLabel.Text = 'AoA';
            app.AoAEditField = uieditfield(app.FlightConditionTab, 'numeric');
            app.AoAEditField.ValueChangedFcn = createCallbackFcn(app, @AoAEditFieldValueChanged, true);
            app.NACAAirfoilTab = uitab(app.TabGroup);
            app.NACAAirfoilTab.Title = 'NACA Airfoil';
            app.NACAFourDigitAirfoilGeneratorLabel = uilabel(app.NACAAirfoilTab);
            app.NACAFourDigitAirfoilGeneratorLabel.Text = 'NACA Four Digit Airfoil Generator';
            app.AirfoilPropertiesPanel = uipanel(app.NACAAirfoilTab);
            app.AirfoilPropertiesPanel.Title = 'Airfoil Properties';
            app.CamberEditFieldLabel = uilabel(app.AirfoilPropertiesPanel);
            app.CamberEditFieldLabel.Text = 'Camber';
            app.CamberEditField = uieditfield(app.AirfoilPropertiesPanel, 'numeric');
            app.CamberEditField.ValueChangedFcn = createCallbackFcn(app, @CamberEditFieldValueChanged, true);
            app.CamberLocationEditFieldLabel = uilabel(app.AirfoilPropertiesPanel);
            app.CamberLocationEditFieldLabel.Text = 'Camber Location';
            app.CamberLocationEditField = uieditfield(app.AirfoilPropertiesPanel, 'numeric');
            app.CamberLocationEditField.ValueChangedFcn = createCallbackFcn(app, @CamberLocationEditFieldValueChanged, true);
            app.MaxThicknessEditFieldLabel = uilabel(app.AirfoilPropertiesPanel);
            app.MaxThicknessEditFieldLabel.Text = 'Max Thickness';
            app.MaxThicknessEditField = uieditfield(app.AirfoilPropertiesPanel, 'numeric');
            app.MaxThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @MaxThicknessEditFieldValueChanged, true);
            app.UIAxes3 = uiaxes(app.AirfoilPropertiesPanel);
            title(app.UIAxes3, 'NACA Four Digit Airfoil')
            xlabel(app.UIAxes3, {'x/c'; ''})
            ylabel(app.UIAxes3, {'t/c'; ''})
            app.UIAxes3.Visible = 'off';
            app.GenerateAirfoilButton = uibutton(app.AirfoilPropertiesPanel, 'push');
            app.GenerateAirfoilButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateAirfoilButtonPushed, true);
            app.GenerateAirfoilButton.Text = 'Generate Airfoil';
            app.NACANumberEditFieldLabel = uilabel(app.AirfoilPropertiesPanel);
            app.NACANumberEditFieldLabel.Text = 'NACA Number';
            app.NACANumberEditField = uieditfield(app.AirfoilPropertiesPanel, 'text');
            app.NACANumberEditField.ValueChangedFcn = createCallbackFcn(app, @NACANumberEditFieldValueChanged, true);
            app.ChordEditFieldLabel = uilabel(app.AirfoilPropertiesPanel);
            app.ChordEditFieldLabel.Text = 'Chord';
            app.ChordEditField = uieditfield(app.AirfoilPropertiesPanel, 'numeric');
            app.ChordEditField.Value = 1;
            app.SendtoExternalFigureButton = uibutton(app.AirfoilPropertiesPanel, 'state');
            app.SendtoExternalFigureButton.Text = 'Send to External Figure';
            app.SectionLiftTab = uitab(app.TabGroup);
            app.SectionLiftTab.Title = 'Section Lift';
            app.ThinAirfoilTheoryCalculatorLabel = uilabel(app.SectionLiftTab);
            app.ThinAirfoilTheoryCalculatorLabel.Text = 'Thin Airfoil Theory Calculator';
            app.SectionPanel = uipanel(app.SectionLiftTab);
            app.SectionPanel.Title = 'Section: Symmetric';
            app.CamberedCheckBox = uicheckbox(app.SectionPanel);
            app.CamberedCheckBox.ValueChangedFcn = createCallbackFcn(app, @CamberedCheckBoxValueChanged, true);
            app.CamberedCheckBox.Text = 'Cambered';
            app.ImportAirfoilButton = uibutton(app.SectionPanel, 'push');
            app.ImportAirfoilButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAirfoilButtonPushed, true);
            app.ImportAirfoilButton.Text = 'Import Airfoil';
            app.AirfoilPropertiesTextAreaLabel = uilabel(app.SectionPanel);
            app.AirfoilPropertiesTextAreaLabel.Text = 'Airfoil Properties';
            app.AirfoilPropertiesTextArea = uitextarea(app.SectionPanel);
            app.AirfoilPropertiesTextArea.Editable = 'off';
            app.UIAxes = uiaxes(app.SectionPanel);
            title(app.UIAxes, 'NACA Airfoil')
            xlabel(app.UIAxes, 'x/c')
            ylabel(app.UIAxes, 't/c')
            app.CombinedCorrectionsPanel = uipanel(app.SectionLiftTab);
            app.CombinedCorrectionsPanel.Title = 'Combined Corrections:';
            app.CompressibilityCheckBox = uicheckbox(app.CombinedCorrectionsPanel);
            app.CompressibilityCheckBox.ValueChangedFcn = createCallbackFcn(app, @CompressibilityCheckBoxValueChanged, true);
            app.CompressibilityCheckBox.Text = 'Compressibility';
            app.MachEditFieldLabel = uilabel(app.CombinedCorrectionsPanel);
            app.MachEditFieldLabel.Text = 'Mach';
            app.MachEditField = uieditfield(app.CombinedCorrectionsPanel, 'numeric');
            app.FiniteWingCheckBox = uicheckbox(app.CombinedCorrectionsPanel);
            app.FiniteWingCheckBox.ValueChangedFcn = createCallbackFcn(app, @FiniteWingCheckBoxValueChanged, true);
            app.FiniteWingCheckBox.Text = 'Finite Wing';
            app.AREditFieldLabel = uilabel(app.CombinedCorrectionsPanel);
            app.AREditFieldLabel.Text = 'AR';
            app.AREditField = uieditfield(app.CombinedCorrectionsPanel, 'numeric');
            app.SweepAngleCheckBox = uicheckbox(app.CombinedCorrectionsPanel);
            app.SweepAngleCheckBox.ValueChangedFcn = createCallbackFcn(app, @SweepAngleCheckBoxValueChanged, true);
            app.SweepAngleCheckBox.Text = 'Sweep Angle';
            app.Label = uilabel(app.CombinedCorrectionsPanel);
            app.Label.Text = 'Δ';
            app.SweepValue = uieditfield(app.CombinedCorrectionsPanel, 'numeric');
            app.Image2 = uiimage(app.CombinedCorrectionsPanel);
            app.Image2.ImageSource = 'CombinedCorrectionsEquation.png';
            app.ThinAirfoilTheoryPanel = uipanel(app.SectionLiftTab);
            app.ThinAirfoilTheoryPanel.Title = 'Thin Airfoil Theory:';
            app.CalculateSectionLiftButton = uibutton(app.ThinAirfoilTheoryPanel, 'push');
            app.CalculateSectionLiftButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSectionLiftButtonPushed, true);
            app.CalculateSectionLiftButton.Text = 'Calculate Section Lift';
            app.AngleofAttackdegreesLabel = uilabel(app.ThinAirfoilTheoryPanel);
            app.AngleofAttackdegreesLabel.Text = 'Angle of Attack (degrees):';
            app.FromEditFieldLabel = uilabel(app.ThinAirfoilTheoryPanel);
            app.FromEditFieldLabel.Text = 'From:';
            app.FromEditField = uieditfield(app.ThinAirfoilTheoryPanel, 'numeric');
            app.ToEditFieldLabel = uilabel(app.ThinAirfoilTheoryPanel);
            app.ToEditFieldLabel.Text = 'To:';
            app.ToEditField = uieditfield(app.ThinAirfoilTheoryPanel, 'numeric');
            app.ToEditField.Value = 12;
            app.UIAxes2 = uiaxes(app.ThinAirfoilTheoryPanel);
            title(app.UIAxes2, {'Section Lift Coefficient vs. Angle of Attack'; ''})
            xlabel(app.UIAxes2, {'AoA, degrees'; ''})
            ylabel(app.UIAxes2, {'C_L'; ''})
            app.Image3 = uiimage(app.ThinAirfoilTheoryPanel);
            app.Image3.ImageSource = 'a0l.png';
            app.lLabel = uilabel(app.ThinAirfoilTheoryPanel);
            app.lLabel.Text = 'α0l = ';
            app.CalculateZeroLiftAoAButton = uibutton(app.ThinAirfoilTheoryPanel, 'push');
            app.CalculateZeroLiftAoAButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateZeroLiftAoAButtonPushed, true);
            app.CalculateZeroLiftAoAButton.Text = 'Calculate Zero Lift AoA';
            app.Image4 = uiimage(app.ThinAirfoilTheoryPanel);
            app.Image4.ImageSource = 'thinTheory.png';
            app.Panel = uipanel(app.SectionLiftTab);
            app.Panel.Title = 'Panel';
            app.Image5 = uiimage(app.Panel);
            app.Image5.ImageSource = 'CMac.png';
            app.CalculateMomentCoefficientButton = uibutton(app.Panel, 'push');
            app.CalculateMomentCoefficientButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateMomentCoefficientButtonPushed, true);
            app.CalculateMomentCoefficientButton.Text = 'Calculate Moment Coefficient';
            app.CmLabel = uilabel(app.Panel);
            app.CmLabel.Text = 'Cm = ';
            app.VehicleLiftDragTab = uitab(app.TabGroup);
            app.VehicleLiftDragTab.Title = 'Vehicle Lift/Drag';
            app.LiftDragOutputTextAreaLabel = uilabel(app.VehicleLiftDragTab);
            app.LiftDragOutputTextAreaLabel.Text = 'Lift & Drag Output';
            app.LiftDragOutputTextArea = uitextarea(app.VehicleLiftDragTab);
            app.Image = uiimage(app.SiaASubOrbitalsSoftwareSuiteV10UIFigure);
            app.Image.ImageSource = 'Company Logo.png';
            % Show the figure after all components are created
            app.SiaASubOrbitalsSoftwareSuiteV10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AeroToolsV1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SiaASubOrbitalsSoftwareSuiteV10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SiaASubOrbitalsSoftwareSuiteV10UIFigure)
        end
    end
end