classdef NACA4DigitGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NACAFourDigitSeriesAirfoilGeneratorUIFigure  matlab.ui.Figure
        AirfoilPropertiesPanel        matlab.ui.container.Panel
        CamberEditFieldLabel          matlab.ui.control.Label
        CamberEditField               matlab.ui.control.NumericEditField
        CamberLocationEditFieldLabel  matlab.ui.control.Label
        CamberLocationEditField       matlab.ui.control.NumericEditField
        MaxThicknessEditFieldLabel    matlab.ui.control.Label
        MaxThicknessEditField         matlab.ui.control.NumericEditField
        UIAxes3                       matlab.ui.control.UIAxes
        GenerateAirfoilButton         matlab.ui.control.Button
        NACANumberEditFieldLabel      matlab.ui.control.Label
        NACANumberEditField           matlab.ui.control.EditField
        ChordEditFieldLabel           matlab.ui.control.Label
        ChordEditField                matlab.ui.control.NumericEditField
        AddAirfoilButton              matlab.ui.control.Button
        CustomAirfoilButton           matlab.ui.control.StateButton
        GenerateNACA4DigitSeriesAirfoilLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = public)
        AirFoil % If this isn't self explanatory you big dumb dumb
        CallingApp
        Comp % The component being edited
        Prop % The property being edited
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, obj, val)
            app.CallingApp = mainapp;
            app.Comp = obj;
            app.Prop = val;
        end

        % Button pushed function: GenerateAirfoilButton
        function GenerateAirfoilButtonPushed(app, event)
            if strcmp(app.NACANumberEditField.Value,'') == 1
                uialert(app.NACAFourDigitSeriesAirfoilGeneratorUIFigure,'Please Input a NACA number','Internal Exception No. Boiling Sausages')
                return
            end
            if app.ChordEditField.Value == 0
                uialert(app.NACAFourDigitSeriesAirfoilGeneratorUIFigure,'Please Input a chord value','Internal Exception No. Boiling lorge Sausages')
                return
            end
            if app.MaxThicknessEditField.Value == 0 
                uialert(app.NACAFourDigitSeriesAirfoilGeneratorUIFigure,'Please Input a maximum thickness value','Internal Exception No. Boiling thicc Sausages')
                return
            end
            clear app.Airfoil
            if app.CustomAirfoilButton.Value == 0
                app.Airfoil = NACA4WingSection(app.NACANumberEditField.Value,app.ChordEditField.Value,128);
                app.Airfoil.PlotFoil(app.UIAxes3)
            elseif app.SendtoExternalFigureButton.Value == 1
                app.Airfoil = NACA4WingSection([app.CamberLocationEditField.Value app.CamberEditField.Value app.MaxThicknessEditField.Value],app.ChordEditField.Value,128);
                app.Airfoil.PlotFoil(app.UIAxes3)
            end
        end

        % Button pushed function: AddAirfoilButton
        function AddAirfoilButtonPushed(app, event)
            ReturnFoil(app.CallingApp,app.Comp,app.Prop)
            delete(app)
        end

        % Value changed function: CustomAirfoilButton
        function CustomAirfoilButtonValueChanged(app, event)
            value = app.CustomAirfoilButton.Value;
            if value==1
                app.NACANumberEditField.Enabled = 0;
            else
                app.NACANumberEditField.Enabled = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NACAFourDigitSeriesAirfoilGeneratorUIFigure = uifigure('Visible', 'off');
            app.NACAFourDigitSeriesAirfoilGeneratorUIFigure.Name = 'NACA Four Digit Series Airfoil Generator';
            app.AirfoilPropertiesPanel = uipanel(app.NACAFourDigitSeriesAirfoilGeneratorUIFigure);
            app.AirfoilPropertiesPanel.Title = 'Airfoil Properties';
            app.CamberEditFieldLabel = uilabel(app.AirfoilPropertiesPanel);
            app.CamberEditFieldLabel.Text = 'Camber';
            app.CamberEditField = uieditfield(app.AirfoilPropertiesPanel, 'numeric');
            app.CamberLocationEditFieldLabel = uilabel(app.AirfoilPropertiesPanel);
            app.CamberLocationEditFieldLabel.Text = 'Camber Location';
            app.CamberLocationEditField = uieditfield(app.AirfoilPropertiesPanel, 'numeric');
            app.MaxThicknessEditFieldLabel = uilabel(app.AirfoilPropertiesPanel);
            app.MaxThicknessEditFieldLabel.Text = 'Max Thickness';
            app.MaxThicknessEditField = uieditfield(app.AirfoilPropertiesPanel, 'numeric');
            app.UIAxes3 = uiaxes(app.AirfoilPropertiesPanel);
            title(app.UIAxes3, 'NACA Four Digit Airfoil')
            xlabel(app.UIAxes3, {'x/c'; ''})
            ylabel(app.UIAxes3, {'t/c'; ''})
            app.GenerateAirfoilButton = uibutton(app.AirfoilPropertiesPanel, 'push');
            app.GenerateAirfoilButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateAirfoilButtonPushed, true);
            app.GenerateAirfoilButton.Text = 'Generate Airfoil';
            app.NACANumberEditFieldLabel = uilabel(app.AirfoilPropertiesPanel);
            app.NACANumberEditFieldLabel.Text = 'NACA Number';
            app.NACANumberEditField = uieditfield(app.AirfoilPropertiesPanel, 'text');
            app.ChordEditFieldLabel = uilabel(app.AirfoilPropertiesPanel);
            app.ChordEditFieldLabel.Text = 'Chord';
            app.ChordEditField = uieditfield(app.AirfoilPropertiesPanel, 'numeric');
            app.ChordEditField.Value = 1;
            app.AddAirfoilButton = uibutton(app.AirfoilPropertiesPanel, 'push');
            app.AddAirfoilButton.ButtonPushedFcn = createCallbackFcn(app, @AddAirfoilButtonPushed, true);
            app.AddAirfoilButton.Text = 'Add Airfoil';
            app.CustomAirfoilButton = uibutton(app.AirfoilPropertiesPanel, 'state');
            app.CustomAirfoilButton.ValueChangedFcn = createCallbackFcn(app, @CustomAirfoilButtonValueChanged, true);
            app.CustomAirfoilButton.Text = 'Custom Airfoil';
            app.GenerateNACA4DigitSeriesAirfoilLabel = uilabel(app.NACAFourDigitSeriesAirfoilGeneratorUIFigure);
            app.GenerateNACA4DigitSeriesAirfoilLabel.Text = 'Generate NACA 4 Digit Series Airfoil';
            app.Image = uiimage(app.NACAFourDigitSeriesAirfoilGeneratorUIFigure);
            app.Image.ImageSource = 'Company Logo.png';
            % Show the figure after all components are created
            app.NACAFourDigitSeriesAirfoilGeneratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NACA4DigitGenerator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NACAFourDigitSeriesAirfoilGeneratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NACAFourDigitSeriesAirfoilGeneratorUIFigure)
        end
    end
end