classdef NewComponentDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddNewVehicleComponentUIFigure  matlab.ui.Figure
        ImportMenu                      matlab.ui.container.Menu
        ImportWingGeometryMenu          matlab.ui.container.Menu
        ImportFuselageGeometryMenu      matlab.ui.container.Menu
        AddNewVehicleComponentLabel     matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Switch                          matlab.ui.control.ToggleSwitch
        AddButton                       matlab.ui.control.Button
        PropertiesPanel                 matlab.ui.container.Panel
        bEditFieldLabel                 matlab.ui.control.Label
        bEditField                      matlab.ui.control.NumericEditField
        RootChordEditFieldLabel         matlab.ui.control.Label
        RootChordEditField              matlab.ui.control.NumericEditField
        TipChordEditFieldLabel          matlab.ui.control.Label
        TipChordEditField               matlab.ui.control.NumericEditField
        TaperRatioEditFieldLabel        matlab.ui.control.Label
        TaperRatioEditField             matlab.ui.control.NumericEditField
        AspectRatioEditFieldLabel       matlab.ui.control.Label
        AspectRatioEditField            matlab.ui.control.NumericEditField
        MeanAerodynamicChordEditFieldLabel  matlab.ui.control.Label
        MeanAerodynamicChordEditField   matlab.ui.control.NumericEditField
        WingSweepEditFieldLabel         matlab.ui.control.Label
        WingSweepEditField              matlab.ui.control.NumericEditField
        WettedPlanformAreaEditFieldLabel  matlab.ui.control.Label
        WettedPlanformAreaEditField     matlab.ui.control.NumericEditField
        ExposedPlanformAreaEditFieldLabel  matlab.ui.control.Label
        ExposedPlanformAreaEditField    matlab.ui.control.NumericEditField
        PlanformAreaEditFieldLabel      matlab.ui.control.Label
        PlanformAreaEditField           matlab.ui.control.NumericEditField
        VolumeEditFieldLabel            matlab.ui.control.Label
        VolumeEditField                 matlab.ui.control.NumericEditField
        NameEditFieldLabel              matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        UnitsDropDownLabel              matlab.ui.control.Label
        UnitsDropDown                   matlab.ui.control.DropDown
        RootAirfoilEditFieldLabel       matlab.ui.control.Label
        RootAirfoilEditField            matlab.ui.control.EditField
        TipAirfoilEditFieldLabel        matlab.ui.control.Label
        TipAirfoilEditField             matlab.ui.control.EditField
    end

    
    properties (Access = public)
        CallingApp % The App responsible for calling this dialog box
        EditingVehicle % The Vehicle we will be adding a component to
        OutputComponent % Description
    end
    
    methods (Access = public)
        
        function ReturnFoil(app,field,foil)
            app.OutputComponent.(field) = foil;
        end
        
        function val_changed(app,~,prop,element)
            app.OutputComponent.(prop) = element.Value;
        end
        
        function DispProp(app,var,gui,xoffset,editable,excludeables)
            delete(gui.Children())
            top_offset = 50;
            fieldsize = [100 22];
            inert = [gui.Position(3)/2 gui.Position(4)-top_offset];
            dy = 30;
            dx = 15;
            f = fieldnames(var);
            l = length(f);
            n = 1;
            for i=1:l
                if IsInList(excludeables,f{i})==0
                    if isa(var.(f{i}),'double')
                        element = uieditfield(gui,'numeric',"ValueChangedFcn",@(element,event) val_changed(app,event,f{i},element));
                        element.Parent = gui;
                        if isempty(var.(f{i}))==0
                            element.Value = var.(f{i});
                        end
                        element.Position = [inert(1)-xoffset inert(2)-((n-1)*dy) fieldsize(1) fieldsize(2)];
                        element.HorizontalAlignment = 'right';
                        label = uilabel(gui,"Text",f{i});
                        label.Parent = gui;
                        w = 150;
                        label.Position(3) = w;
                        label.Position(1:2) = [inert(1)-dx-w-xoffset inert(2)-((n-1)*dy)];
                        label.HorizontalAlignment = 'right';
                    elseif ischar(var.(f{i})) || isa(var.(f{i}),'string') || isa(var.(f{i}),'NACA4')
                        element = uieditfield(gui,"text","ValueChangedFcn",@(element,event) val_changed(app,event,f{i},element));
                        element.Parent = gui;
                        if isempty(var.(f{i}))==0
                            if isa(var.(f{i}),'NACA4')
                                element.Value = var.(f{i}).Name;
                                element.HorizontalAlignment = 'right';
                            elseif strcmp(f{i},'Units')
                                delete(element)
                                element = uidropdown(gui,'Items',{'SI','FPS'},'Value',var.(f{i}));
                                if isempty(var.(f{i}))==1
                                    element.Value = "SI";
                                end
                            else
                                element.Value = var.(f{i});
                                element.HorizontalAlignment = 'right';
                            end
                        end
                        element.Position = [inert(1)-xoffset inert(2)-((n-1)*dy) fieldsize(1) fieldsize(2)];
                        label = uilabel(gui,"Text",f{i});
                        label.Parent = gui;
                        w = 150;
                        label.Position(3) = w;
                        label.Position(1:2) = [inert(1)-dx-w-xoffset inert(2)-((n-1)*dy)];
                        label.HorizontalAlignment = 'right';
                    elseif strcmp(f{i},'Units')
                        element = uidropdown(gui,'Items',{'SI','FPS'},'Value',var.(f{i}),"ValueChangedFcn",@(element,event) val_changed(app,event,f{i},element));
                        element.Parent = gui;
                        if isempty(var.(f{i}))==0
                            element.Value = 'SI';
                        end
                        element.Position = [inert(1)-xoffset inert(2)-((n-1)*dy) fieldsize(1) fieldsize(2)];
                        element.HorizontalAlignment = 'right';
                        label = uilabel(gui,"Text",f{i});
                        label.Parent = gui;
                        w = 150;
                        label.Position(3) = w;
                        label.Position(1:2) = [inert(1)-dx-w-xoffset inert(2)-((n-1)*dy)];
                        label.HorizontalAlignment = 'right';
                    end
                    element.Editable = editable;
                    n = n + 1;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, vehicle)
            app.CallingApp = mainapp;
            app.Switch.Value = 'Wing';
            app.OutputComponent = WingGeometry;
            app.EditingVehicle = vehicle;
            DispProp(app,app.OutputComponent,app.PropertiesPanel,0,1,[])
        end

        % Menu selected function: ImportWingGeometryMenu
        function ImportWingGeometryMenuSelected(app, event)
            file = uigetfile('*.xlsx');
            cell = readcell(file);
            newwing = WingGeometry;
            wing = newwing.ImportFromCell(cell);
            DispProp(app,wing,app.PropertiesPanel,30,1,[])
            app.OutputComponent = wing;
        end

        % Menu selected function: ImportFuselageGeometryMenu
        function ImportFuselageGeometryMenuSelected(app, event)
            file = uigetfile('*.xlsx');
            cell = readcell(file);
            newfuse = FuselageGeometry;
            fuse = newfuse.ImportFromCell(cell);
            DispProp(app,fuse,app.PropertiesPanel,30,1,[])
            app.OutputComponent = fuse;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'Wing')
                if isa(app.OutputComponent,'FuselageGeometry')
                    app.OutputComponent = WingGeometry;
                end
                DispProp(app,app.OutputComponent,app.PropertiesPanel,30,1,[])
            elseif strcmp(value,'Body')
                if isa(app.OutputComponent,'WingGeometry')
                    app.OutputComponent = FuselageGeometry;
                end
                DispProp(app,app.OutputComponent,app.PropertiesPanel,30,1,[])
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
%             children = app.PropertiesPanel.Children();
%             vals = cell(1);
%             val = 0;
%             for i=1:length(children)
%                 if strcmp(children(i).Type,'uieditfield') || strcmp(children(i).Type,'uinumericeditfield')
%                     val = val + 1;
%                     vals{val,1} =  children(i).Value;
%                 end
%             end
%             f = fieldnames(app.OutputComponent);
%             for v = 1:length(vals)
%                 if strcmp(f{v},'RootAirfoil')
%                     app.OutputComponent.(f{v}) = NACA4WingSection(vals{v},app.OutputComponent.Rc,128);
%                 elseif strcmp(f{v},'TipAirfoil')
%                     app.OutputComponent.(f{v}) = NACA4WingSection(vals{v},app.OutputComponent.Tc,128);
%                 else
%                     app.OutputComponent.(f{v}) = vals{v};
%                 end
                 
%             end
            AddVehicleComponent(app.CallingApp,app.EditingVehicle.Name,app.OutputComponent)
            UpdateTree(app.CallingApp)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddNewVehicleComponentUIFigure = uifigure('Visible', 'off');
            app.AddNewVehicleComponentUIFigure.Name = 'Add New Vehicle Component';
            app.ImportMenu = uimenu(app.AddNewVehicleComponentUIFigure);
            app.ImportMenu.Text = 'Import';
            app.ImportWingGeometryMenu = uimenu(app.ImportMenu);
            app.ImportWingGeometryMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportWingGeometryMenuSelected, true);
            app.ImportWingGeometryMenu.Text = 'Import Wing Geometry';
            app.ImportFuselageGeometryMenu = uimenu(app.ImportMenu);
            app.ImportFuselageGeometryMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportFuselageGeometryMenuSelected, true);
            app.ImportFuselageGeometryMenu.Text = 'Import Fuselage Geometry';
            app.AddNewVehicleComponentLabel = uilabel(app.AddNewVehicleComponentUIFigure);
            app.AddNewVehicleComponentLabel.Text = 'Add New Vehicle Component';
            app.Image = uiimage(app.AddNewVehicleComponentUIFigure);
            app.Image.ImageSource = 'Company Logo.png';
            app.Switch = uiswitch(app.AddNewVehicleComponentUIFigure, 'toggle');
            app.Switch.Items = {'Body', 'Wing'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Wing';
            app.AddButton = uibutton(app.AddNewVehicleComponentUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.PropertiesPanel = uipanel(app.AddNewVehicleComponentUIFigure);
            app.PropertiesPanel.Title = 'Properties';
            app.bEditFieldLabel = uilabel(app.PropertiesPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.PropertiesPanel, 'numeric');
            app.RootChordEditFieldLabel = uilabel(app.PropertiesPanel);
            app.RootChordEditFieldLabel.Text = 'Root Chord';
            app.RootChordEditField = uieditfield(app.PropertiesPanel, 'numeric');
            app.TipChordEditFieldLabel = uilabel(app.PropertiesPanel);
            app.TipChordEditFieldLabel.Text = 'Tip Chord';
            app.TipChordEditField = uieditfield(app.PropertiesPanel, 'numeric');
            app.TaperRatioEditFieldLabel = uilabel(app.PropertiesPanel);
            app.TaperRatioEditFieldLabel.Text = 'Taper Ratio';
            app.TaperRatioEditField = uieditfield(app.PropertiesPanel, 'numeric');
            app.AspectRatioEditFieldLabel = uilabel(app.PropertiesPanel);
            app.AspectRatioEditFieldLabel.Text = 'Aspect Ratio';
            app.AspectRatioEditField = uieditfield(app.PropertiesPanel, 'numeric');
            app.MeanAerodynamicChordEditFieldLabel = uilabel(app.PropertiesPanel);
            app.MeanAerodynamicChordEditFieldLabel.Text = 'Mean Aerodynamic Chord';
            app.MeanAerodynamicChordEditField = uieditfield(app.PropertiesPanel, 'numeric');
            app.WingSweepEditFieldLabel = uilabel(app.PropertiesPanel);
            app.WingSweepEditFieldLabel.Text = 'Wing Sweep';
            app.WingSweepEditField = uieditfield(app.PropertiesPanel, 'numeric');
            app.WettedPlanformAreaEditFieldLabel = uilabel(app.PropertiesPanel);
            app.WettedPlanformAreaEditFieldLabel.Text = 'Wetted Planform Area';
            app.WettedPlanformAreaEditField = uieditfield(app.PropertiesPanel, 'numeric');
            app.ExposedPlanformAreaEditFieldLabel = uilabel(app.PropertiesPanel);
            app.ExposedPlanformAreaEditFieldLabel.Text = 'Exposed Planform Area';
            app.ExposedPlanformAreaEditField = uieditfield(app.PropertiesPanel, 'numeric');
            app.PlanformAreaEditFieldLabel = uilabel(app.PropertiesPanel);
            app.PlanformAreaEditFieldLabel.Text = 'Planform Area';
            app.PlanformAreaEditField = uieditfield(app.PropertiesPanel, 'numeric');
            app.VolumeEditFieldLabel = uilabel(app.PropertiesPanel);
            app.VolumeEditFieldLabel.Text = 'Volume';
            app.VolumeEditField = uieditfield(app.PropertiesPanel, 'numeric');
            app.NameEditFieldLabel = uilabel(app.PropertiesPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.PropertiesPanel, 'text');
            app.UnitsDropDownLabel = uilabel(app.PropertiesPanel);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.PropertiesPanel);
            app.UnitsDropDown.Items = {'SI', 'Imperial'};
            app.UnitsDropDown.Value = 'SI';
            app.RootAirfoilEditFieldLabel = uilabel(app.PropertiesPanel);
            app.RootAirfoilEditFieldLabel.Text = 'Root Airfoil';
            app.RootAirfoilEditField = uieditfield(app.PropertiesPanel, 'text');
            app.RootAirfoilEditField.Value = 'NACA4 Num';
            app.TipAirfoilEditFieldLabel = uilabel(app.PropertiesPanel);
            app.TipAirfoilEditFieldLabel.Text = 'Tip Airfoil';
            app.TipAirfoilEditField = uieditfield(app.PropertiesPanel, 'text');
            app.TipAirfoilEditField.Value = 'NACA4 Num';
            % Show the figure after all components are created
            app.AddNewVehicleComponentUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewComponentDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddNewVehicleComponentUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddNewVehicleComponentUIFigure)
        end
    end
end