classdef VehicleNameDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EnteraVehicleNameUIFigure  matlab.ui.Figure
        VehicleNameEditFieldLabel  matlab.ui.control.Label
        VehicleNameEditField       matlab.ui.control.EditField
        OkayButton                 matlab.ui.control.Button
        Image                      matlab.ui.control.Image
    end

    
    properties (Access = public)
        CallingApp % Parent App calling for the dialog request Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Button pushed function: OkayButton
        function OkayButtonPushed(app, event)
            name = app.VehicleNameEditField.Value;
            if isempty(name)
                uialert(app.EnteraVehicleNameUIFigure,'You must enter a name for the new vehicle!','ERROR')
            else
                charname = char(name);
                for i=1:length(charname)
                    if strcmp(charname(i),' ')
                        uialert(app.EnteraVehicleNameUIFigure,'Your vehicle name must have no spaces! (Sorry I know its dumb, blame Mathworks)','ERROR')
                        return
                    end
                end
                NewVehicle(app.CallingApp,name)
                UpdateTree(app.CallingApp)
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EnteraVehicleNameUIFigure = uifigure('Visible', 'off');
            app.EnteraVehicleNameUIFigure.Name = 'Enter a Vehicle Name';
            app.VehicleNameEditFieldLabel = uilabel(app.EnteraVehicleNameUIFigure);
            app.VehicleNameEditFieldLabel.Text = 'Vehicle Name';
            app.VehicleNameEditField = uieditfield(app.EnteraVehicleNameUIFigure, 'text');
            app.OkayButton = uibutton(app.EnteraVehicleNameUIFigure, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @OkayButtonPushed, true);
            app.OkayButton.Text = 'Okay';
            app.Image = uiimage(app.EnteraVehicleNameUIFigure);
            app.Image.ImageSource = 'Company Logo.png';
            % Show the figure after all components are created
            app.EnteraVehicleNameUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VehicleNameDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EnteraVehicleNameUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EnteraVehicleNameUIFigure)
        end
    end
end