classdef DesignProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FlightDynamicsNondimensionalcoefficientconverterUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FlightConditionGeometryTab      matlab.ui.container.Tab
        SteadyStateCoefficientsLabel    matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        Label                           matlab.ui.control.Label
        ConvertInertiasButton           matlab.ui.control.Button
        CL1EditFieldLabel               matlab.ui.control.Label
        CL1EditField                    matlab.ui.control.NumericEditField
        CD1EditFieldLabel               matlab.ui.control.Label
        CD1EditField                    matlab.ui.control.NumericEditField
        CTx1EditFieldLabel              matlab.ui.control.Label
        CTx1EditField                   matlab.ui.control.NumericEditField
        Cm1EditFieldLabel               matlab.ui.control.Label
        Cm1EditField                    matlab.ui.control.NumericEditField
        CmT1EditFieldLabel              matlab.ui.control.Label
        CmT1EditField                   matlab.ui.control.NumericEditField
        IxxbEditFieldLabel              matlab.ui.control.Label
        IxxbEditField                   matlab.ui.control.NumericEditField
        IyybEditFieldLabel              matlab.ui.control.Label
        IyybEditField                   matlab.ui.control.NumericEditField
        IzzbEditFieldLabel              matlab.ui.control.Label
        IzzbEditField                   matlab.ui.control.NumericEditField
        IxybEditFieldLabel              matlab.ui.control.Label
        IxybEditField                   matlab.ui.control.NumericEditField
        IxzbEditFieldLabel              matlab.ui.control.Label
        IxzbEditField                   matlab.ui.control.NumericEditField
        IyzbEditFieldLabel              matlab.ui.control.Label
        IyzbEditField                   matlab.ui.control.NumericEditField
        IxxsEditFieldLabel              matlab.ui.control.Label
        IxxsEditField                   matlab.ui.control.NumericEditField
        IyysEditFieldLabel              matlab.ui.control.Label
        IyysEditField                   matlab.ui.control.NumericEditField
        IzzsEditFieldLabel              matlab.ui.control.Label
        IzzsEditField                   matlab.ui.control.NumericEditField
        IxysEditFieldLabel              matlab.ui.control.Label
        IxysEditField                   matlab.ui.control.NumericEditField
        IxzsEditFieldLabel              matlab.ui.control.Label
        IxzsEditField                   matlab.ui.control.NumericEditField
        IyzsEditFieldLabel              matlab.ui.control.Label
        IyzsEditField                   matlab.ui.control.NumericEditField
        FlightConditionLabel            matlab.ui.control.Label
        GeometryWeightandInertiasLabel  matlab.ui.control.Label
        AltitudeftEditFieldLabel        matlab.ui.control.Label
        AltitudeftEditField             matlab.ui.control.NumericEditField
        AirDensityslugscuftEditFieldLabel  matlab.ui.control.Label
        AirDensityslugscuftEditField    matlab.ui.control.NumericEditField
        AirspeedftsecEditFieldLabel     matlab.ui.control.Label
        AirspeedftsecEditField          matlab.ui.control.NumericEditField
        MachNoEditFieldLabel            matlab.ui.control.Label
        MachNoEditField                 matlab.ui.control.NumericEditField
        DynamicPressurelbsqftEditFieldLabel  matlab.ui.control.Label
        DynamicPressurelbsqftEditField  matlab.ui.control.NumericEditField
        CenterofGravityMACEditFieldLabel  matlab.ui.control.Label
        CenterofGravityMACEditField     matlab.ui.control.NumericEditField
        AngleofAttackdegEditFieldLabel  matlab.ui.control.Label
        AngleofAttackdegEditField       matlab.ui.control.NumericEditField
        WingAreasqftEditFieldLabel      matlab.ui.control.Label
        WingAreasqftEditField           matlab.ui.control.NumericEditField
        WingSpanftEditFieldLabel        matlab.ui.control.Label
        WingSpanftEditField             matlab.ui.control.NumericEditField
        WingChordftEditFieldLabel       matlab.ui.control.Label
        WingChordftEditField            matlab.ui.control.NumericEditField
        WeightlbEditFieldLabel          matlab.ui.control.Label
        WeightlbEditField               matlab.ui.control.NumericEditField
        ExportDataButton                matlab.ui.control.Button
        Label_4                         matlab.ui.control.Label
        LongitudinalTab                 matlab.ui.container.Tab
        NondimensionalDerivativesLabel  matlab.ui.control.Label
        CDuEditFieldLabel               matlab.ui.control.Label
        CDuEditField                    matlab.ui.control.NumericEditField
        CDaEditFieldLabel               matlab.ui.control.Label
        CDaEditField                    matlab.ui.control.NumericEditField
        CTXuEditFieldLabel              matlab.ui.control.Label
        CTXuEditField                   matlab.ui.control.NumericEditField
        CLuEditFieldLabel               matlab.ui.control.Label
        CLuEditField                    matlab.ui.control.NumericEditField
        CLaEditFieldLabel               matlab.ui.control.Label
        CLaEditField                    matlab.ui.control.NumericEditField
        CLaEditField_2Label             matlab.ui.control.Label
        CLaEditField_2                  matlab.ui.control.NumericEditField
        CLqEditFieldLabel               matlab.ui.control.Label
        CLqEditField                    matlab.ui.control.NumericEditField
        CMuEditFieldLabel               matlab.ui.control.Label
        CMuEditField                    matlab.ui.control.NumericEditField
        CMaEditFieldLabel               matlab.ui.control.Label
        CMaEditField                    matlab.ui.control.NumericEditField
        CMaEditField_2Label             matlab.ui.control.Label
        CMaEditField_2                  matlab.ui.control.NumericEditField
        CMqEditFieldLabel               matlab.ui.control.Label
        CMqEditField                    matlab.ui.control.NumericEditField
        CMTuEditFieldLabel              matlab.ui.control.Label
        CMTuEditField                   matlab.ui.control.NumericEditField
        CMTaEditFieldLabel              matlab.ui.control.Label
        CMTaEditField                   matlab.ui.control.NumericEditField
        CLdeEditField_2Label            matlab.ui.control.Label
        CLdeEditField_2                 matlab.ui.control.NumericEditField
        CDdeEditFieldLabel              matlab.ui.control.Label
        CDdeEditField                   matlab.ui.control.NumericEditField
        CMdeEditFieldLabel              matlab.ui.control.Label
        CMdeEditField                   matlab.ui.control.NumericEditField
        DimensionalDerivativesLabel     matlab.ui.control.Label
        XuEditFieldLabel                matlab.ui.control.Label
        XuEditField                     matlab.ui.control.NumericEditField
        XaEditFieldLabel                matlab.ui.control.Label
        XaEditField                     matlab.ui.control.NumericEditField
        XTuEditFieldLabel               matlab.ui.control.Label
        XTuEditField                    matlab.ui.control.NumericEditField
        ZuEditFieldLabel                matlab.ui.control.Label
        ZuEditField                     matlab.ui.control.NumericEditField
        ZaEditFieldLabel                matlab.ui.control.Label
        ZaEditField                     matlab.ui.control.NumericEditField
        ZaEditField_2Label              matlab.ui.control.Label
        ZaEditField_2                   matlab.ui.control.NumericEditField
        ZqEditFieldLabel                matlab.ui.control.Label
        ZqEditField                     matlab.ui.control.NumericEditField
        MuEditFieldLabel                matlab.ui.control.Label
        MuEditField                     matlab.ui.control.NumericEditField
        MaEditFieldLabel                matlab.ui.control.Label
        MaEditField                     matlab.ui.control.NumericEditField
        MaEditField_2Label              matlab.ui.control.Label
        MaEditField_2                   matlab.ui.control.NumericEditField
        MqEditFieldLabel                matlab.ui.control.Label
        MqEditField                     matlab.ui.control.NumericEditField
        MTuEditFieldLabel               matlab.ui.control.Label
        MTuEditField                    matlab.ui.control.NumericEditField
        MTaEditFieldLabel               matlab.ui.control.Label
        MTaEditField                    matlab.ui.control.NumericEditField
        ZdeEditFieldLabel               matlab.ui.control.Label
        ZdeEditField                    matlab.ui.control.NumericEditField
        XdeEditFieldLabel               matlab.ui.control.Label
        XdeEditField                    matlab.ui.control.NumericEditField
        MdeEditFieldLabel               matlab.ui.control.Label
        MdeEditField                    matlab.ui.control.NumericEditField
        LongitudinalDerivativesLabel    matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        LateralTab                      matlab.ui.container.Tab
        NondimensionalDerivativesLabel_2  matlab.ui.control.Label
        DimensionalDerivativesLabel_2   matlab.ui.control.Label
        LateralDerivativesLabel         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        CalculateButton_2               matlab.ui.control.Button
        CybEditFieldLabel               matlab.ui.control.Label
        CybEditField                    matlab.ui.control.NumericEditField
        CyrEditFieldLabel               matlab.ui.control.Label
        CyrEditField                    matlab.ui.control.NumericEditField
        CypEditFieldLabel               matlab.ui.control.Label
        CypEditField                    matlab.ui.control.NumericEditField
        CydaEditFieldLabel              matlab.ui.control.Label
        CydaEditField                   matlab.ui.control.NumericEditField
        CydrEditFieldLabel              matlab.ui.control.Label
        CydrEditField                   matlab.ui.control.NumericEditField
        CnbEditFieldLabel               matlab.ui.control.Label
        CnbEditField                    matlab.ui.control.NumericEditField
        CnrEditFieldLabel               matlab.ui.control.Label
        CnrEditField                    matlab.ui.control.NumericEditField
        CnpEditFieldLabel               matlab.ui.control.Label
        CnpEditField                    matlab.ui.control.NumericEditField
        CndaEditFieldLabel              matlab.ui.control.Label
        CndaEditField                   matlab.ui.control.NumericEditField
        CndrEditFieldLabel              matlab.ui.control.Label
        CndrEditField                   matlab.ui.control.NumericEditField
        ClbEditFieldLabel               matlab.ui.control.Label
        ClbEditField                    matlab.ui.control.NumericEditField
        ClrEditFieldLabel               matlab.ui.control.Label
        ClrEditField                    matlab.ui.control.NumericEditField
        ClpEditFieldLabel               matlab.ui.control.Label
        ClpEditField                    matlab.ui.control.NumericEditField
        CldaEditFieldLabel              matlab.ui.control.Label
        CldaEditField                   matlab.ui.control.NumericEditField
        CldrEditFieldLabel              matlab.ui.control.Label
        CldrEditField                   matlab.ui.control.NumericEditField
        YbEditFieldLabel                matlab.ui.control.Label
        YbEditField                     matlab.ui.control.NumericEditField
        YrEditFieldLabel                matlab.ui.control.Label
        YrEditField                     matlab.ui.control.NumericEditField
        YpEditFieldLabel                matlab.ui.control.Label
        YpEditField                     matlab.ui.control.NumericEditField
        YdaEditFieldLabel               matlab.ui.control.Label
        YdaEditField                    matlab.ui.control.NumericEditField
        YdrEditFieldLabel               matlab.ui.control.Label
        YdrEditField                    matlab.ui.control.NumericEditField
        NbEditFieldLabel                matlab.ui.control.Label
        NbEditField                     matlab.ui.control.NumericEditField
        NrEditFieldLabel                matlab.ui.control.Label
        NrEditField                     matlab.ui.control.NumericEditField
        NpEditFieldLabel                matlab.ui.control.Label
        NpEditField                     matlab.ui.control.NumericEditField
        NdaEditFieldLabel               matlab.ui.control.Label
        NdaEditField                    matlab.ui.control.NumericEditField
        NdrEditFieldLabel               matlab.ui.control.Label
        NdrEditField                    matlab.ui.control.NumericEditField
        LbEditFieldLabel                matlab.ui.control.Label
        LbEditField                     matlab.ui.control.NumericEditField
        LrEditFieldLabel                matlab.ui.control.Label
        LrEditField                     matlab.ui.control.NumericEditField
        LpEditFieldLabel                matlab.ui.control.Label
        LpEditField                     matlab.ui.control.NumericEditField
        LdaEditFieldLabel               matlab.ui.control.Label
        LdaEditField                    matlab.ui.control.NumericEditField
        LdrEditFieldLabel               matlab.ui.control.Label
        LdrEditField                    matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConvertInertiasButton
        function ConvertInertiasButtonPushed(app, event)
            Ixx1 = app.IxxbEditField.Value;
            Iyy1 = app.IyybEditField.Value;
            Izz1 = app.IzzbEditField.Value;
            Ixy1 = app.IxybEditField.Value;
            Ixz1 = app.IxzbEditField.Value;
            Iyz1 = app.IyzbEditField.Value;
            a = app.AngleofAttackdegEditField.Value;
            body = app.Switch;
            A = [cosd(a)^2 sind(a)^2 -sind(2*a); sind(a)^2 cosd(a)^2 sind(2*a)^2; 1/2*sind(2*a) -1/2*sind(2*a) cosd(2*a)];
            if strcmp(body.Value,'Body Axis')
                Iyz = A*[Iyy1; Izz1; Iyz1];
                Ixy = A*[Ixx1; Iyy1; Ixy1];
                Ixz = A*[Ixx1; Izz1; Ixz1];
                I = [Ixy, Iyz, Ixz];
                app.IxxsEditField.Value = I(1,3);
                app.IyysEditField.Value = I(2,1);
                app.IzzsEditField.Value = I(2,2);
                %app.IxysEditField.Value = I(3,1);
                %app.IyzsEditField.Value = I(3,2);
                app.IxysEditField.Value = 0;
                app.IxzsEditField.Value = I(3,3);
                app.IyzsEditField.Value = 0;
            elseif strcmp(body.Value,'Stability Axis')
                Iyz = A\[Iyy1; Izz1; Iyz1];
                Ixy = A\[Ixx1; Iyy1; Ixy1];
                Ixz = A\[Ixx1; Izz1; Ixz1];
                I = [Ixy, Iyz, Ixz];
                app.IxxsEditField.Value = I(1,3);
                app.IyysEditField.Value = I(2,1);
                app.IzzsEditField.Value = I(2,2);
                %app.IxysEditField.Value = I(3,1);
                %app.IyzsEditField.Value = I(3,2);
                app.IxysEditField.Value = 0;
                app.IxzsEditField.Value = I(3,3);
                app.IyzsEditField.Value = 0;
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Flight Conditions
            A = app.AltitudeftEditField.Value;
            rho = app.AirDensityslugscuftEditField.Value;
            U = app.AirspeedftsecEditField.Value;
            M = app.MachNoEditField.Value;
            q = app.DynamicPressurelbsqftEditField.Value;
            cg = app.CenterofGravityMACEditField.Value;
            aoa = app.AngleofAttackdegEditField.Value;
            
            % Geometry, Weight and Inertias
            S = app.WingAreasqftEditField.Value;
            b = app.WingSpanftEditField.Value;
            c = app.WingChordftEditField.Value;
            W = app.WeightlbEditField.Value;
            m = W/32.2;
            
            Ixx = app.IxxsEditField.Value;
            Iyy = app.IyysEditField.Value;
            Izz = app.IzzsEditField.Value;
            %Ixy = app.IxysEditField.Value;
            Ixy = 0;
            Ixz = app.IxzsEditField.Value;
            %Iyz = app.IyzsEditField.Value;
            Iyz = 0;
            
            % Steady-state Coefficients
            CL1 = app.CL1EditField.Value;
            CD1 = app.CD1EditField.Value;
            CTx1 = app.CTx1EditField.Value;
            Cm1 = app.Cm1EditField.Value;
            CmT1 = app.CmT1EditField.Value;
            
            % Longitudinal nondimensional derivatives 
            CDu = app.CDuEditField.Value;
            CDa = app.CDaEditField.Value;
            CTXu = app.CTXuEditField.Value;
            CLu = app.CLuEditField.Value;
            CLa = app.CLaEditField.Value;
            CLap = app.CLaEditField_2.Value;
            CLq = app.CLqEditField.Value;
            CMu = app.CMuEditField.Value;
            CMa = app.CMaEditField.Value;
            CMap = app.CMaEditField_2.Value;
            CMq = app.CMqEditField.Value;
            CMTu = app.CMTuEditField.Value;
            CMTa = app.CMTaEditField.Value;
            CLde = app.CLdeEditField_2.Value;
            CDde = app.CDdeEditField.Value;
            CMde = app.CMdeEditField.Value;
            
            % Longitudinal dimensional derivatives
            app.XuEditField.Value = -q*S*(CDu + 2*CD1)/(m*U);
            app.XaEditField.Value = -q*S*(CDa - CL1)/m;
            app.XTuEditField.Value = q*S*(CTXu + 2*CTx1)/(m*U);
            app.ZuEditField.Value = -q*S*(CLu + 2*CL1)/(m*U);
            app.ZaEditField.Value = -q*S*(CLa + CD1)/m;
            app.ZaEditField_2.Value = -q*S*c*CLap/(2*m*U);
            app.ZqEditField.Value = -q*S*c*CLq/(2*m*U);
            app.MuEditField.Value = q*S*c*(CMu + 2*Cm1)/(Iyy*U);
            app.MaEditField.Value = q*S*c*CMa/Iyy;
            app.MaEditField_2.Value = q*S*c^2*CMap/(2*Iyy*U);
            app.MqEditField.Value = q*S*c^2*CMq/(2*Iyy*U);
            app.MTuEditField.Value = q*S*c*(CMTu + 2*CmT1)/(Iyy*U);
            app.MTaEditField.Value = q*S*c*CMTa/Iyy;
            app.ZdeEditField.Value = -q*S*CLde/m;
            app.XdeEditField.Value = -q*S*CDde/m;
            app.MdeEditField.Value = q*S*c*CMde/Iyy;
            

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'Body Axis')
                % Inputs
                app.IxxbEditFieldLabel.Text = 'Ixxb';
                app.IyybEditFieldLabel.Text = 'Iyyb';
                app.IzzbEditFieldLabel.Text = 'Izzb';
                app.IxybEditFieldLabel.Text = 'Ixyb';
                app.IxzbEditFieldLabel.Text = 'Ixzb';
                app.IyzbEditFieldLabel.Text = 'Iyzb';
                
                % Outputs
                app.IxxsEditFieldLabel.Text = 'Ixxs';
                app.IyysEditFieldLabel.Text = 'Iyys';
                app.IzzsEditFieldLabel.Text = 'Izzs';
                app.IxysEditFieldLabel.Text = 'Ixys';
                app.IxzsEditFieldLabel.Text = 'Ixzs';
                app.IyzsEditFieldLabel.Text = 'Iyzs';
            elseif strcmp(value,'Stability Axis')
                % Inputs
                app.IxxbEditFieldLabel.Text = 'Ixxs';
                app.IyybEditFieldLabel.Text = 'Iyys';
                app.IzzbEditFieldLabel.Text = 'Izzs';
                app.IxybEditFieldLabel.Text = 'Ixys';
                app.IxzbEditFieldLabel.Text = 'Ixzs';
                app.IyzbEditFieldLabel.Text = 'Iyzs';
                
                % Outputs
                app.IxxsEditFieldLabel.Text = 'Ixxb';
                app.IyysEditFieldLabel.Text = 'Iyyb';
                app.IzzsEditFieldLabel.Text = 'Izzb';
                app.IxysEditFieldLabel.Text = 'Ixyb';
                app.IxzsEditFieldLabel.Text = 'Ixzb';
                app.IyzsEditFieldLabel.Text = 'Iyzb';
            end
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            % Flight Conditions
            A = app.AltitudeftEditField.Value;
            rho = app.AirDensityslugscuftEditField.Value;
            U = app.AirspeedftsecEditField.Value;
            M = app.MachNoEditField.Value;
            q = app.DynamicPressurelbsqftEditField.Value;
            cg = app.CenterofGravityMACEditField.Value;
            aoa = app.AngleofAttackdegEditField.Value;
            
            % Geometry, Weight and Inertias
            S = app.WingAreasqftEditField.Value;
            b = app.WingSpanftEditField.Value;
            c = app.WingChordftEditField.Value;
            W = app.WeightlbEditField.Value;
            m = W/32.2;
            
            Ixx = app.IxxsEditField.Value;
            Iyy = app.IyysEditField.Value;
            Izz = app.IzzsEditField.Value;
            %Ixy = app.IxysEditField.Value;
            Ixy = 0;
            Ixz = app.IxzsEditField.Value;
            %Iyz = app.IyzsEditField.Value;
            Iyz = 0;
            
            % Steady-state Coefficients
            CL1 = app.CL1EditField.Value;
            CD1 = app.CD1EditField.Value;
            CTx1 = app.CTx1EditField.Value;
            Cm1 = app.Cm1EditField.Value;
            CmT1 = app.CmT1EditField.Value;
            
            % Non-dimensional Derivatives
            Cyb = app.CybEditField.Value;
            Cyr = app.CyrEditField.Value;
            Cyp = app.CypEditField.Value;
            Cyda = app.CydaEditField.Value;
            Cydr = app.CydrEditField.Value;
            Cnb = app.CnbEditField.Value;
            Cnr = app.CnrEditField.Value;
            Cnp = app.CnpEditField.Value;
            Cnda = app.CndaEditField.Value;
            Cndr = app.CndrEditField.Value;
            Clb = app.ClbEditField.Value;
            Clr = app.ClrEditField.Value;
            Clp = app.ClpEditField.Value;
            Clda = app.CldaEditField.Value;
            Cldr = app.CldrEditField.Value;
            
            % Dimensional Derivatives
            Yb = q*S*Cyb/m;
            Yr = q*S*b*Cyr/(2*m*U);
            Yp = q*S*b*Cyp/(2*m*U);
            Yda = q*S*Cyda/m;
            Ydr = q*S*Cydr/m;
            Nb = q*S*b*Cnb/Izz;
            Nr = q*S*b^2*Cnr/(2*Izz*U);
            Np = q*S*b^2*Cnp/(2*Izz*U);
            Nda = q*S*b*Cnda/Izz;
            Ndr = q*S*b*Cndr/Izz;
            Lb = q*S*b*Clb/Ixx;
            Lr = q*S*b^2*Clr/(2*Ixx*U);
            Lp = q*S*b^2*Clp/(2*Ixx*U);
            Lda = q*S*b*Clda/Ixx;
            Ldr = q*S*b*Cldr/Ixx;
            
            app.YbEditField.Value = Yb;
            app.YrEditField.Value = Yr;
            app.YpEditField.Value = Yp;
            app.YdaEditField.Value = Yda;
            app.YdrEditField.Value = Ydr;
            app.NbEditField.Value = Nb;
            app.NrEditField.Value = Nr;
            app.NpEditField.Value = Np;
            app.NdaEditField.Value = Nda;
            app.NdrEditField.Value = Ndr;
            app.LbEditField.Value = Lb;
            app.LrEditField.Value = Lr;
            app.LpEditField.Value = Lp;
            app.LdaEditField.Value = Lda;
            app.LdrEditField.Value = Ldr;
            
            
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            % Create the file
            [file,path] = uiputfile('Data.txt','Save as a Textfile');
            file = fopen(strcat(path,file),'w');
            
            % Mark the file with the date and time it was generated
            date_and_time = string(datetime);
            fprintf(file,strcat('Data Exported on:\t',string(date_and_time),'\n'));
            
            %% Page one
            
            % Print the Flight Condition Data
            fprintf(file,'\tFlight Condition\n');
            fprintf(file,'\t  Altitude\t\t%.2f ft.\n',app.AltitudeftEditField.Value);
            fprintf(file,'\t  Air Density\t\t%.8f slugs/cu. ft.\n',app.AirDensityslugscuftEditField.Value);
            fprintf(file,'\t  Airspeed\t\t%.2f ft/sec\n',app.AirspeedftsecEditField.Value);
            fprintf(file,'\t  Center of Gravity\t\t%2.2f %%MAC\n',app.CenterofGravityMACEditField.Value);
            fprintf(file,'\t  Angle of Attack\t\t%1.4f deg.\n',app.AngleofAttackdegEditField.Value);
            
            % Geometry, Weight and Inertias
            fprintf(file,'\tGeometry, Weight, and Inertias\n');
            fprintf(file,'\t  Wing Area\t\t%.2f sq. ft.\n',app.WingAreasqftEditField.Value);
            fprintf(file,'\t  Wing Span\t\t%.2f ft.\n',app.WingSpanftEditField.Value);
            fprintf(file,'\t  Wing Chord\t\t%.2f ft.\n',app.WingChordftEditField.Value);
            fprintf(file,'\t  Weight\t\t%d lb.\n',app.WeightlbEditField.Value);
            if strcmp(app.IxxbEditFieldLabel.Text,'Ixxb')
                fprintf(file,'\t  Ixxb\t%.0f slug-sq. ft.\tIxxs\t%.0f\tslug-sq. ft.\n',app.IxxbEditField.Value,app.IxxsEditField.Value);
                fprintf(file,'\t  Iyyb\t%.0f slug-sq. ft.\tIyys\t%.0f\tslug-sq. ft.\n',app.IyybEditField.Value,app.IyysEditField.Value);
                fprintf(file,'\t  Izzb\t%.0f slug-sq. ft.\tIzzs\t%.0f\tslug-sq. ft.\n',app.IzzbEditField.Value,app.IzzsEditField.Value);
                
                fprintf(file,'\t  Ixyb\t%.0f slug-sq. ft.\tIxys\t%.0f\tslug-sq. ft.\n',app.IxybEditField.Value,app.IxysEditField.Value);
                fprintf(file,'\t  Ixzb\t%.0f slug-sq. ft.\tIxzs\t%.0f\tslug-sq. ft.\n',app.IxzbEditField.Value,app.IxzsEditField.Value);
                fprintf(file,'\t  Iyzb\t%.0f slug-sq. ft.\tIyzs\t%.0f\tslug-sq. ft.\n',app.IyzbEditField.Value,app.IyzsEditField.Value);
            else
                fprintf(file,'\t  Ixxb\t%.0f slug-sq. ft.\tIxxs\t%.0f\tslug-sq. ft.\n',app.IxxsEditField.Value,app.IxxbEditField.Value);
                fprintf(file,'\t  Iyyb\t%.0f slug-sq. ft.\tIyys\t%.0f\tslug-sq. ft.\n',app.IyysEditField.Value,app.IyybEditField.Value);
                fprintf(file,'\t  Izzb\t%.0f slug-sq. ft.\tIzzs\t%.0f\tslug-sq. ft.\n',app.IzzbEditField.Value,app.IzzsEditField.Value);
                
                fprintf(file,'\t  Ixyb\t%.0f slug-sq. ft.\tIxys\t%.0f\tslug-sq. ft.\n',app.IxysEditField.Value,app.IxybEditField.Value);
                fprintf(file,'\t  Ixzb\t%.0f slug-sq. ft.\tIxzs\t%.0f\tslug-sq. ft.\n',app.IxzsEditField.Value,app.IxzbEditField.Value);
                fprintf(file,'\t  Iyzb\t%.0f slug-sq. ft.\tIyzs\t%.0f\tslug-sq. ft.\n',app.IyzsEditField.Value,app.IyzbEditField.Value);
            end
            
            % Steady State Coefficients
            fprintf(file,'\nSteady State Coefficients\n');
            fprintf(file,'CL1\t%.5f\n',app.CL1EditField.Value);
            fprintf(file,'CD1\t%.5f\n',app.CD1EditField.Value);
            fprintf(file,'CTx1\t%.5f\n',app.CTx1EditField.Value);
            fprintf(file,'Cm1\t%.5f\n',app.Cm1EditField.Value);
            fprintf(file,'CmT1\t%.5f\n\n',app.CmT1EditField.Value);
            
            % Longitudinal 
            % Non-dimensional derivatives           Dimensional Derivatives
            fprintf(file,'NONDIMENSIONAL DERIVATIVES\t\t\tDIMENSIONAL DERIVATIVES\n');
            fprintf(file,'\t\t\tLONGITUDINAL DERIVATIVES\n');
            fprintf(file,'CDu\t%.5f 1/1\t\t\t\tXu\t%.4f 1/sec\n',app.CDuEditField.Value,app.XuEditField.Value);
            fprintf(file,'CDa\t%.5f 1/rad\t\t\t\tXa\t%.4f ft/sec^2\n',app.CDaEditField.Value,app.XaEditField.Value);
            fprintf(file,'CTXu\t%.5f 1/1\t\t\t\tXTu\t%.4f 1/sec\n',app.CTXuEditField.Value,app.XTuEditField.Value);
            fprintf(file,'CLu\t%.5f 1/1\t\t\t\tZu\t%.4f 1/sec\n',app.CLuEditField.Value,app.ZuEditField.Value);
            fprintf(file,'CLa\t%.5f 1/1\t\t\t\tZa\t%.4f 1/sec\n',app.CLaEditField.Value,app.ZaEditField.Value);
            fprintf(file,"CLa'\t%.5f 1/1\t\t\t\tZa'\t%.4f 1/sec\n",app.CLaEditField_2.Value,app.ZaEditField_2.Value);
            fprintf(file,'CLq\t%.5f 1/1\t\t\t\tZq\t%.4f 1/sec\n',app.CLqEditField.Value,app.ZqEditField.Value);
            fprintf(file,'CMu\t%.5f 1/1\t\t\t\tMu\t%.4f 1/sec\n',app.CMuEditField.Value,app.MuEditField.Value);
            fprintf(file,'CMa\t%.5f 1/1\t\t\t\tMa\t%.4f 1/sec\n',app.CMaEditField.Value,app.MaEditField.Value);
            fprintf(file,"CMa'\t%.5f 1/1\t\t\t\tMa'\t%.4f 1/sec\n",app.CMaEditField_2.Value,app.MaEditField_2.Value);
            fprintf(file,'CMq\t%.5f 1/1\t\t\t\tMq\t%.4f 1/sec\n',app.CMqEditField.Value,app.MqEditField.Value);
            fprintf(file,'CMTu\t%.5f 1/1\t\t\t\tMTu\t%.4f 1/sec\n',app.CMTuEditField.Value,app.MTuEditField.Value);
            fprintf(file,'CMTa\t%.5f 1/1\t\t\t\tMTa\t%.4f 1/sec\n',app.CMTaEditField.Value,app.MTaEditField.Value);
            fprintf(file,'CLde\t%.5f 1/1\t\t\t\tZde\t%.4f 1/sec\n',app.CLdeEditField_2.Value,app.ZdeEditField.Value);
            fprintf(file,'CDde\t%.5f 1/1\t\t\t\tXde\t%.4f 1/sec\n',app.CDdeEditField.Value,app.XdeEditField.Value);
            fprintf(file,'CMde\t%.5f 1/1\t\t\t\tMde\t%.4f 1/sec\n',app.CMdeEditField.Value,app.MdeEditField.Value);
            
            
            %% Page 2
            fprintf(file,'\n\n\n');
            % Mark the file with the date and time it was generated
            date_and_time = string(datetime);
            fprintf(file,strcat('Data Exported on:\t',string(date_and_time),'\tPage 2\n'));
            
            %% Page one
            
            % Print the Flight Condition Data
            fprintf(file,'\tFlight Condition\n');
            fprintf(file,'\t  Altitude\t\t%.2f ft.\n',app.AltitudeftEditField.Value);
            fprintf(file,'\t  Air Density\t\t%.8f slugs/cu. ft.\n',app.AirDensityslugscuftEditField.Value);
            fprintf(file,'\t  Airspeed\t\t%.2f ft/sec\n',app.AirspeedftsecEditField.Value);
            fprintf(file,'\t  Center of Gravity\t\t%2.2f %%MAC\n',app.CenterofGravityMACEditField.Value);
            fprintf(file,'\t  Angle of Attack\t\t%1.4f deg.\n',app.AngleofAttackdegEditField.Value);
            
            % Geometry, Weight and Inertias
            fprintf(file,'\tGeometry, Weight, and Inertias\n');
            fprintf(file,'\t  Wing Area\t\t%.2f sq. ft.\n',app.WingAreasqftEditField.Value);
            fprintf(file,'\t  Wing Span\t\t%.2f ft.\n',app.WingSpanftEditField.Value);
            fprintf(file,'\t  Wing Chord\t\t%.2f ft.\n',app.WingChordftEditField.Value);
            fprintf(file,'\t  Weight\t\t%d lb.\n',app.WeightlbEditField.Value);
            if strcmp(app.IxxbEditFieldLabel.Text,'Ixxb')
                fprintf(file,'\t  Ixxb\t%.0f slug-sq. ft.\tIxxs\t%.0f\tslug-sq. ft.\n',app.IxxbEditField.Value,app.IxxsEditField.Value);
                fprintf(file,'\t  Iyyb\t%.0f slug-sq. ft.\tIyys\t%.0f\tslug-sq. ft.\n',app.IyybEditField.Value,app.IyysEditField.Value);
                fprintf(file,'\t  Izzb\t%.0f slug-sq. ft.\tIzzs\t%.0f\tslug-sq. ft.\n',app.IzzbEditField.Value,app.IzzsEditField.Value);
                
                fprintf(file,'\t  Ixyb\t%.0f slug-sq. ft.\tIxys\t%.0f\tslug-sq. ft.\n',app.IxybEditField.Value,app.IxysEditField.Value);
                fprintf(file,'\t  Ixzb\t%.0f slug-sq. ft.\tIxzs\t%.0f\tslug-sq. ft.\n',app.IxzbEditField.Value,app.IxzsEditField.Value);
                fprintf(file,'\t  Iyzb\t%.0f slug-sq. ft.\tIyzs\t%.0f\tslug-sq. ft.\n',app.IyzbEditField.Value,app.IyzsEditField.Value);
            else
                fprintf(file,'\t  Ixxb\t%.0f slug-sq. ft.\tIxxs\t%.0f\tslug-sq. ft.\n',app.IxxsEditField.Value,app.IxxbEditField.Value);
                fprintf(file,'\t  Iyyb\t%.0f slug-sq. ft.\tIyys\t%.0f\tslug-sq. ft.\n',app.IyysEditField.Value,app.IyybEditField.Value);
                fprintf(file,'\t  Izzb\t%.0f slug-sq. ft.\tIzzs\t%.0f\tslug-sq. ft.\n',app.IzzbEditField.Value,app.IzzsEditField.Value);
                
                fprintf(file,'\t  Ixyb\t%.0f slug-sq. ft.\tIxys\t%.0f\tslug-sq. ft.\n',app.IxysEditField.Value,app.IxybEditField.Value);
                fprintf(file,'\t  Ixzb\t%.0f slug-sq. ft.\tIxzs\t%.0f\tslug-sq. ft.\n',app.IxzsEditField.Value,app.IxzbEditField.Value);
                fprintf(file,'\t  Iyzb\t%.0f slug-sq. ft.\tIyzs\t%.0f\tslug-sq. ft.\n',app.IyzsEditField.Value,app.IyzbEditField.Value);
            end
            
            % Steady State Coefficients
            fprintf(file,'\nSteady State Coefficients\n');
            fprintf(file,'CL1\t%.5f\n',app.CL1EditField.Value);
            fprintf(file,'CD1\t%.5f\n',app.CD1EditField.Value);
            fprintf(file,'CTx1\t%.5f\n',app.CTx1EditField.Value);
            fprintf(file,'Cm1\t%.5f\n',app.Cm1EditField.Value);
            fprintf(file,'CmT1\t%.5f\n\n',app.CmT1EditField.Value);
            
            % Longitudinal 
            % Non-dimensional derivatives           Dimensional Derivatives
            fprintf(file,'NONDIMENSIONAL DERIVATIVES\t\t\tDIMENSIONAL DERIVATIVES\n');
            fprintf(file,'\t\t\tLATERAL DERIVATIVES\n');
            fprintf(file,'Cyb\t%.5f 1/1\t\t\t\tYb\t%.4f 1/sec\n',app.CybEditField.Value,app.YbEditField.Value);
            fprintf(file,'Cyr\t%.5f 1/rad\t\t\t\tYr\t%.4f ft/sec^2\n',app.CyrEditField.Value,app.YrEditField.Value);
            fprintf(file,'Cyp\t%.5f 1/1\t\t\t\tYp\t%.4f 1/sec\n',app.CypEditField.Value,app.YpEditField.Value);
            fprintf(file,'Cyda\t%.5f 1/1\t\t\t\tYda\t%.4f 1/sec\n',app.CydaEditField.Value,app.YdaEditField.Value);
            fprintf(file,'Cydr\t%.5f 1/1\t\t\t\tYdr\t%.4f 1/sec\n',app.CydrEditField.Value,app.YdrEditField.Value);
            fprintf(file,"Cnb\t%.5f 1/1\t\t\t\tNb\t%.4f 1/sec\n",app.CnbEditField.Value,app.NbEditField.Value);
            fprintf(file,'Cnr\t%.5f 1/1\t\t\t\tNr\t%.4f 1/sec\n',app.CnrEditField.Value,app.NrEditField.Value);
            fprintf(file,'Cnp\t%.5f 1/1\t\t\t\tNp\t%.4f 1/sec\n',app.CnpEditField.Value,app.NpEditField.Value);
            fprintf(file,'Cnda\t%.5f 1/1\t\t\t\tNda\t%.4f 1/sec\n',app.CndaEditField.Value,app.NdaEditField.Value);
            fprintf(file,"Cndr\t%.5f 1/1\t\t\t\tNdr\t%.4f 1/sec\n",app.CndrEditField.Value,app.NdrEditField.Value);
            fprintf(file,'Clb\t%.5f 1/1\t\t\t\tLb\t%.4f 1/sec\n',app.ClbEditField.Value,app.LbEditField.Value);
            fprintf(file,'Clr\t%.5f 1/1\t\t\t\tLr\t%.4f 1/sec\n',app.ClrEditField.Value,app.LrEditField.Value);
            fprintf(file,'Clp\t%.5f 1/1\t\t\t\tLp\t%.4f 1/sec\n',app.ClpEditField.Value,app.LpEditField.Value);
            fprintf(file,'Clda\t%.5f 1/1\t\t\t\tLda\t%.4f 1/sec\n',app.CldaEditField.Value,app.LdaEditField.Value);
            fprintf(file,'Cldr\t%.5f 1/1\t\t\t\tLdr\t%.4f 1/sec\n',app.CldrEditField.Value,app.LdrEditField.Value);
            
            app.Label_4.Text = strcat('Saved under: ',path);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FlightDynamicsNondimensionalcoefficientconverterUIFigure = uifigure('Visible', 'off');
            app.FlightDynamicsNondimensionalcoefficientconverterUIFigure.Name = 'Flight Dynamics Nondimensional coefficient converter';
            app.TabGroup = uitabgroup(app.FlightDynamicsNondimensionalcoefficientconverterUIFigure);
            app.FlightConditionGeometryTab = uitab(app.TabGroup);
            app.FlightConditionGeometryTab.Title = 'Flight Condition/Geometry';
            app.SteadyStateCoefficientsLabel = uilabel(app.FlightConditionGeometryTab);
            app.SteadyStateCoefficientsLabel.Text = 'Steady State Coefficients';
            app.Switch = uiswitch(app.FlightConditionGeometryTab, 'slider');
            app.Switch.Items = {'Body Axis', 'Stability Axis'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Body Axis';
            app.Label = uilabel(app.FlightConditionGeometryTab);
            app.Label.Text = '→';
            app.ConvertInertiasButton = uibutton(app.FlightConditionGeometryTab, 'push');
            app.ConvertInertiasButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertInertiasButtonPushed, true);
            app.ConvertInertiasButton.Text = 'Convert Inertias';
            app.CL1EditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.CL1EditFieldLabel.Text = 'CL1';
            app.CL1EditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.CL1EditField.Value = 0.26;
            app.CD1EditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.CD1EditFieldLabel.Text = 'CD1';
            app.CD1EditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.CD1EditField.Value = 0.03;
            app.CTx1EditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.CTx1EditFieldLabel.Text = 'CTx1';
            app.CTx1EditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.CTx1EditField.Value = 0.03;
            app.Cm1EditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.Cm1EditFieldLabel.Text = 'Cm1';
            app.Cm1EditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.CmT1EditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.CmT1EditFieldLabel.Text = 'CmT1';
            app.CmT1EditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.IxxbEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.IxxbEditFieldLabel.Text = 'Ixxb';
            app.IxxbEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.IxxbEditField.ValueDisplayFormat = '%.0f';
            app.IxxbEditField.Value = 25000;
            app.IyybEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.IyybEditFieldLabel.Text = 'Iyyb';
            app.IyybEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.IyybEditField.ValueDisplayFormat = '%.0f';
            app.IyybEditField.Value = 122200;
            app.IzzbEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.IzzbEditFieldLabel.Text = 'Izzb';
            app.IzzbEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.IzzbEditField.ValueDisplayFormat = '%.0f';
            app.IzzbEditField.Value = 139800;
            app.IxybEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.IxybEditFieldLabel.Text = 'Ixyb';
            app.IxybEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.IxybEditField.ValueDisplayFormat = '%.0f';
            app.IxzbEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.IxzbEditFieldLabel.Text = 'Ixzb';
            app.IxzbEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.IxzbEditField.ValueDisplayFormat = '%.0f';
            app.IxzbEditField.Value = 2200;
            app.IyzbEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.IyzbEditFieldLabel.Text = 'Iyzb';
            app.IyzbEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.IyzbEditField.ValueDisplayFormat = '%.0f';
            app.IxxsEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.IxxsEditFieldLabel.Text = 'Ixxs';
            app.IxxsEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.IxxsEditField.ValueDisplayFormat = '%.0f';
            app.IyysEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.IyysEditFieldLabel.Text = 'Iyys';
            app.IyysEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.IyysEditField.ValueDisplayFormat = '%.0f';
            app.IzzsEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.IzzsEditFieldLabel.Text = 'Izzs';
            app.IzzsEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.IzzsEditField.ValueDisplayFormat = '%.0f';
            app.IxysEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.IxysEditFieldLabel.Text = 'Ixys';
            app.IxysEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.IxysEditField.ValueDisplayFormat = '%.0f';
            app.IxzsEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.IxzsEditFieldLabel.Text = 'Ixzs';
            app.IxzsEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.IxzsEditField.ValueDisplayFormat = '%.0f';
            app.IyzsEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.IyzsEditFieldLabel.Text = 'Iyzs';
            app.IyzsEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.IyzsEditField.ValueDisplayFormat = '%.0f';
            app.FlightConditionLabel = uilabel(app.FlightConditionGeometryTab);
            app.FlightConditionLabel.Text = 'Flight Condition';
            app.GeometryWeightandInertiasLabel = uilabel(app.FlightConditionGeometryTab);
            app.GeometryWeightandInertiasLabel.Text = 'Geometry, Weight and Inertias';
            app.AltitudeftEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.AltitudeftEditFieldLabel.Text = 'Altitude, (ft)';
            app.AltitudeftEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.AltitudeftEditField.ValueDisplayFormat = '%.3f';
            app.AltitudeftEditField.Value = 35000;
            app.AirDensityslugscuftEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.AirDensityslugscuftEditFieldLabel.Text = 'Air Density, (slugs/cu. ft)';
            app.AirDensityslugscuftEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.AirDensityslugscuftEditField.Value = 0.00073854;
            app.AirspeedftsecEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.AirspeedftsecEditFieldLabel.Text = 'Airspeed, ft/sec';
            app.AirspeedftsecEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.AirspeedftsecEditField.ValueDisplayFormat = '%.3f';
            app.AirspeedftsecEditField.Value = 876;
            app.MachNoEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.MachNoEditFieldLabel.Text = 'Mach No.';
            app.MachNoEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.MachNoEditField.ValueDisplayFormat = '%.3f';
            app.MachNoEditField.Value = 0.9;
            app.DynamicPressurelbsqftEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.DynamicPressurelbsqftEditFieldLabel.Text = 'Dynamic Pressure, (lb/ sq. ft.)';
            app.DynamicPressurelbsqftEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.DynamicPressurelbsqftEditField.ValueDisplayFormat = '%.3f';
            app.DynamicPressurelbsqftEditField.Value = 283.37;
            app.CenterofGravityMACEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.CenterofGravityMACEditFieldLabel.Text = 'Center of Gravity, (%MAC)';
            app.CenterofGravityMACEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.CenterofGravityMACEditField.ValueDisplayFormat = '%.3f';
            app.CenterofGravityMACEditField.Value = 29;
            app.AngleofAttackdegEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.AngleofAttackdegEditFieldLabel.Text = 'Angle of Attack, (deg)';
            app.AngleofAttackdegEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.AngleofAttackdegEditField.ValueDisplayFormat = '%.3f';
            app.AngleofAttackdegEditField.Value = 2.6;
            app.WingAreasqftEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.WingAreasqftEditFieldLabel.Text = 'Wing Area, (sq. ft)';
            app.WingAreasqftEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.WingAreasqftEditField.ValueDisplayFormat = '%.3f';
            app.WingAreasqftEditField.Value = 530;
            app.WingSpanftEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.WingSpanftEditFieldLabel.Text = 'Wing Span, (ft)';
            app.WingSpanftEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.WingSpanftEditField.ValueDisplayFormat = '%.3f';
            app.WingSpanftEditField.Value = 38.7;
            app.WingChordftEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.WingChordftEditFieldLabel.Text = 'Wing Chord, (ft)';
            app.WingChordftEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.WingChordftEditField.ValueDisplayFormat = '%.3f';
            app.WingChordftEditField.Value = 16;
            app.WeightlbEditFieldLabel = uilabel(app.FlightConditionGeometryTab);
            app.WeightlbEditFieldLabel.Text = 'Weight, (lb)';
            app.WeightlbEditField = uieditfield(app.FlightConditionGeometryTab, 'numeric');
            app.WeightlbEditField.ValueDisplayFormat = '%.3f';
            app.WeightlbEditField.Value = 39000;
            app.ExportDataButton = uibutton(app.FlightConditionGeometryTab, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.Label_4 = uilabel(app.FlightConditionGeometryTab);
            app.Label_4.Text = '';
            app.LongitudinalTab = uitab(app.TabGroup);
            app.LongitudinalTab.Title = 'Longitudinal';
            app.NondimensionalDerivativesLabel = uilabel(app.LongitudinalTab);
            app.NondimensionalDerivativesLabel.Text = 'Nondimensional Derivatives';
            app.CDuEditFieldLabel = uilabel(app.LongitudinalTab);
            app.CDuEditFieldLabel.Text = 'CDu';
            app.CDuEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.CDuEditField.ValueDisplayFormat = '%.5f';
            app.CDuEditField.Value = 0.027;
            app.CDaEditFieldLabel = uilabel(app.LongitudinalTab);
            app.CDaEditFieldLabel.Text = 'CDa';
            app.CDaEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.CDaEditField.ValueDisplayFormat = '%.5f';
            app.CDaEditField.Value = 0.3;
            app.CTXuEditFieldLabel = uilabel(app.LongitudinalTab);
            app.CTXuEditFieldLabel.Text = 'CTXu';
            app.CTXuEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.CTXuEditField.ValueDisplayFormat = '%.5f';
            app.CTXuEditField.Value = -0.064;
            app.CLuEditFieldLabel = uilabel(app.LongitudinalTab);
            app.CLuEditFieldLabel.Text = 'CLu';
            app.CLuEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.CLuEditField.ValueDisplayFormat = '%.5f';
            app.CLuEditField.Value = 0.27;
            app.CLaEditFieldLabel = uilabel(app.LongitudinalTab);
            app.CLaEditFieldLabel.Text = 'CLa';
            app.CLaEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.CLaEditField.ValueDisplayFormat = '%.5f';
            app.CLaEditField.Value = 3.75;
            app.CLaEditField_2Label = uilabel(app.LongitudinalTab);
            app.CLaEditField_2Label.Text = 'CLa''';
            app.CLaEditField_2 = uieditfield(app.LongitudinalTab, 'numeric');
            app.CLaEditField_2.ValueDisplayFormat = '%.5f';
            app.CLaEditField_2.Value = 0.86;
            app.CLqEditFieldLabel = uilabel(app.LongitudinalTab);
            app.CLqEditFieldLabel.Text = 'CLq';
            app.CLqEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.CLqEditField.ValueDisplayFormat = '%.5f';
            app.CLqEditField.Value = 1.8;
            app.CMuEditFieldLabel = uilabel(app.LongitudinalTab);
            app.CMuEditFieldLabel.Text = 'CMu';
            app.CMuEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.CMuEditField.ValueDisplayFormat = '%.5f';
            app.CMuEditField.Value = -0.117;
            app.CMaEditFieldLabel = uilabel(app.LongitudinalTab);
            app.CMaEditFieldLabel.Text = 'CMa';
            app.CMaEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.CMaEditField.ValueDisplayFormat = '%.5f';
            app.CMaEditField.Value = -0.4;
            app.CMaEditField_2Label = uilabel(app.LongitudinalTab);
            app.CMaEditField_2Label.Text = 'CMa''';
            app.CMaEditField_2 = uieditfield(app.LongitudinalTab, 'numeric');
            app.CMaEditField_2.ValueDisplayFormat = '%.5f';
            app.CMaEditField_2.Value = -1.3;
            app.CMqEditFieldLabel = uilabel(app.LongitudinalTab);
            app.CMqEditFieldLabel.Text = 'CMq';
            app.CMqEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.CMqEditField.ValueDisplayFormat = '%.5f';
            app.CMqEditField.Value = -2.7;
            app.CMTuEditFieldLabel = uilabel(app.LongitudinalTab);
            app.CMTuEditFieldLabel.Text = 'CMTu';
            app.CMTuEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.CMTuEditField.ValueDisplayFormat = '%.5f';
            app.CMTaEditFieldLabel = uilabel(app.LongitudinalTab);
            app.CMTaEditFieldLabel.Text = 'CMTa';
            app.CMTaEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.CMTaEditField.ValueDisplayFormat = '%.5f';
            app.CLdeEditField_2Label = uilabel(app.LongitudinalTab);
            app.CLdeEditField_2Label.Text = 'CLde';
            app.CLdeEditField_2 = uieditfield(app.LongitudinalTab, 'numeric');
            app.CLdeEditField_2.ValueDisplayFormat = '%.5f';
            app.CLdeEditField_2.Value = 0.4;
            app.CDdeEditFieldLabel = uilabel(app.LongitudinalTab);
            app.CDdeEditFieldLabel.Text = 'CDde';
            app.CDdeEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.CDdeEditField.ValueDisplayFormat = '%.5f';
            app.CDdeEditField.Value = -0.1;
            app.CMdeEditFieldLabel = uilabel(app.LongitudinalTab);
            app.CMdeEditFieldLabel.Text = 'CMde';
            app.CMdeEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.CMdeEditField.ValueDisplayFormat = '%.5f';
            app.CMdeEditField.Value = -0.58;
            app.DimensionalDerivativesLabel = uilabel(app.LongitudinalTab);
            app.DimensionalDerivativesLabel.Text = 'Dimensional Derivatives';
            app.XuEditFieldLabel = uilabel(app.LongitudinalTab);
            app.XuEditFieldLabel.Text = 'Xu';
            app.XuEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.XuEditField.ValueDisplayFormat = '%.4f';
            app.XaEditFieldLabel = uilabel(app.LongitudinalTab);
            app.XaEditFieldLabel.Text = 'Xa';
            app.XaEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.XaEditField.ValueDisplayFormat = '%.4f';
            app.XTuEditFieldLabel = uilabel(app.LongitudinalTab);
            app.XTuEditFieldLabel.Text = 'XTu';
            app.XTuEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.XTuEditField.ValueDisplayFormat = '%.4f';
            app.ZuEditFieldLabel = uilabel(app.LongitudinalTab);
            app.ZuEditFieldLabel.Text = 'Zu';
            app.ZuEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.ZuEditField.ValueDisplayFormat = '%.4f';
            app.ZaEditFieldLabel = uilabel(app.LongitudinalTab);
            app.ZaEditFieldLabel.Text = 'Za';
            app.ZaEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.ZaEditField.ValueDisplayFormat = '%.4f';
            app.ZaEditField_2Label = uilabel(app.LongitudinalTab);
            app.ZaEditField_2Label.Text = 'Za''';
            app.ZaEditField_2 = uieditfield(app.LongitudinalTab, 'numeric');
            app.ZaEditField_2.ValueDisplayFormat = '%.4f';
            app.ZqEditFieldLabel = uilabel(app.LongitudinalTab);
            app.ZqEditFieldLabel.Text = 'Zq';
            app.ZqEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.ZqEditField.ValueDisplayFormat = '%.4f';
            app.MuEditFieldLabel = uilabel(app.LongitudinalTab);
            app.MuEditFieldLabel.Text = 'Mu';
            app.MuEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.MuEditField.ValueDisplayFormat = '%.4f';
            app.MaEditFieldLabel = uilabel(app.LongitudinalTab);
            app.MaEditFieldLabel.Text = 'Ma';
            app.MaEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.MaEditField.ValueDisplayFormat = '%.4f';
            app.MaEditField_2Label = uilabel(app.LongitudinalTab);
            app.MaEditField_2Label.Text = 'Ma''';
            app.MaEditField_2 = uieditfield(app.LongitudinalTab, 'numeric');
            app.MaEditField_2.ValueDisplayFormat = '%.4f';
            app.MqEditFieldLabel = uilabel(app.LongitudinalTab);
            app.MqEditFieldLabel.Text = 'Mq';
            app.MqEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.MqEditField.ValueDisplayFormat = '%.4f';
            app.MTuEditFieldLabel = uilabel(app.LongitudinalTab);
            app.MTuEditFieldLabel.Text = 'MTu';
            app.MTuEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.MTuEditField.ValueDisplayFormat = '%.4f';
            app.MTaEditFieldLabel = uilabel(app.LongitudinalTab);
            app.MTaEditFieldLabel.Text = 'MTa';
            app.MTaEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.MTaEditField.ValueDisplayFormat = '%.4f';
            app.ZdeEditFieldLabel = uilabel(app.LongitudinalTab);
            app.ZdeEditFieldLabel.Text = 'Zde';
            app.ZdeEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.ZdeEditField.ValueDisplayFormat = '%.4f';
            app.XdeEditFieldLabel = uilabel(app.LongitudinalTab);
            app.XdeEditFieldLabel.Text = 'Xde';
            app.XdeEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.XdeEditField.ValueDisplayFormat = '%.4f';
            app.MdeEditFieldLabel = uilabel(app.LongitudinalTab);
            app.MdeEditFieldLabel.Text = 'Mde';
            app.MdeEditField = uieditfield(app.LongitudinalTab, 'numeric');
            app.MdeEditField.ValueDisplayFormat = '%.4f';
            app.LongitudinalDerivativesLabel = uilabel(app.LongitudinalTab);
            app.LongitudinalDerivativesLabel.Text = 'Longitudinal Derivatives';
            app.Label_2 = uilabel(app.LongitudinalTab);
            app.Label_2.Text = '→';
            app.CalculateButton = uibutton(app.LongitudinalTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.LateralTab = uitab(app.TabGroup);
            app.LateralTab.Title = 'Lateral';
            app.NondimensionalDerivativesLabel_2 = uilabel(app.LateralTab);
            app.NondimensionalDerivativesLabel_2.Text = 'Nondimensional Derivatives';
            app.DimensionalDerivativesLabel_2 = uilabel(app.LateralTab);
            app.DimensionalDerivativesLabel_2.Text = 'Dimensional Derivatives';
            app.LateralDerivativesLabel = uilabel(app.LateralTab);
            app.LateralDerivativesLabel.Text = 'Lateral Derivatives';
            app.Label_3 = uilabel(app.LateralTab);
            app.Label_3.Text = '→';
            app.CalculateButton_2 = uibutton(app.LateralTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.CybEditFieldLabel = uilabel(app.LateralTab);
            app.CybEditFieldLabel.Text = 'Cyb';
            app.CybEditField = uieditfield(app.LateralTab, 'numeric');
            app.CybEditField.ValueDisplayFormat = '%.5f';
            app.CybEditField.Value = -0.68;
            app.CyrEditFieldLabel = uilabel(app.LateralTab);
            app.CyrEditFieldLabel.Text = 'Cyr';
            app.CyrEditField = uieditfield(app.LateralTab, 'numeric');
            app.CyrEditField.ValueDisplayFormat = '%.5f';
            app.CypEditFieldLabel = uilabel(app.LateralTab);
            app.CypEditFieldLabel.Text = 'Cyp';
            app.CypEditField = uieditfield(app.LateralTab, 'numeric');
            app.CypEditField.ValueDisplayFormat = '%.5f';
            app.CydaEditFieldLabel = uilabel(app.LateralTab);
            app.CydaEditFieldLabel.Text = 'Cyda';
            app.CydaEditField = uieditfield(app.LateralTab, 'numeric');
            app.CydaEditField.ValueDisplayFormat = '%.5f';
            app.CydaEditField.Value = -0.016;
            app.CydrEditFieldLabel = uilabel(app.LateralTab);
            app.CydrEditFieldLabel.Text = 'Cydr';
            app.CydrEditField = uieditfield(app.LateralTab, 'numeric');
            app.CydrEditField.ValueDisplayFormat = '%.5f';
            app.CydrEditField.Value = 0.095;
            app.CnbEditFieldLabel = uilabel(app.LateralTab);
            app.CnbEditFieldLabel.Text = 'Cnb';
            app.CnbEditField = uieditfield(app.LateralTab, 'numeric');
            app.CnbEditField.ValueDisplayFormat = '%.5f';
            app.CnbEditField.Value = 0.125;
            app.CnrEditFieldLabel = uilabel(app.LateralTab);
            app.CnrEditFieldLabel.Text = 'Cnr';
            app.CnrEditField = uieditfield(app.LateralTab, 'numeric');
            app.CnrEditField.ValueDisplayFormat = '%.5f';
            app.CnrEditField.Value = -0.27;
            app.CnpEditFieldLabel = uilabel(app.LateralTab);
            app.CnpEditFieldLabel.Text = 'Cnp';
            app.CnpEditField = uieditfield(app.LateralTab, 'numeric');
            app.CnpEditField.ValueDisplayFormat = '%.5f';
            app.CnpEditField.Value = -0.036;
            app.CndaEditFieldLabel = uilabel(app.LateralTab);
            app.CndaEditFieldLabel.Text = 'Cnda';
            app.CndaEditField = uieditfield(app.LateralTab, 'numeric');
            app.CndaEditField.ValueDisplayFormat = '%.5f';
            app.CndaEditField.Value = -0.001;
            app.CndrEditFieldLabel = uilabel(app.LateralTab);
            app.CndrEditFieldLabel.Text = 'Cndr';
            app.CndrEditField = uieditfield(app.LateralTab, 'numeric');
            app.CndrEditField.ValueDisplayFormat = '%.5f';
            app.CndrEditField.Value = -0.066;
            app.ClbEditFieldLabel = uilabel(app.LateralTab);
            app.ClbEditFieldLabel.Text = 'Clb';
            app.ClbEditField = uieditfield(app.LateralTab, 'numeric');
            app.ClbEditField.ValueDisplayFormat = '%.5f';
            app.ClbEditField.Value = -0.08;
            app.ClrEditFieldLabel = uilabel(app.LateralTab);
            app.ClrEditFieldLabel.Text = 'Clr';
            app.ClrEditField = uieditfield(app.LateralTab, 'numeric');
            app.ClrEditField.ValueDisplayFormat = '%.5f';
            app.ClrEditField.Value = 0.07;
            app.ClpEditFieldLabel = uilabel(app.LateralTab);
            app.ClpEditFieldLabel.Text = 'Clp';
            app.ClpEditField = uieditfield(app.LateralTab, 'numeric');
            app.ClpEditField.ValueDisplayFormat = '%.5f';
            app.ClpEditField.Value = -0.24;
            app.CldaEditFieldLabel = uilabel(app.LateralTab);
            app.CldaEditFieldLabel.Text = 'Clda';
            app.CldaEditField = uieditfield(app.LateralTab, 'numeric');
            app.CldaEditField.ValueDisplayFormat = '%.5f';
            app.CldaEditField.Value = 0.042;
            app.CldrEditFieldLabel = uilabel(app.LateralTab);
            app.CldrEditFieldLabel.Text = 'Cldr';
            app.CldrEditField = uieditfield(app.LateralTab, 'numeric');
            app.CldrEditField.ValueDisplayFormat = '%.5f';
            app.CldrEditField.Value = 0.006;
            app.YbEditFieldLabel = uilabel(app.LateralTab);
            app.YbEditFieldLabel.Text = 'Yb';
            app.YbEditField = uieditfield(app.LateralTab, 'numeric');
            app.YbEditField.ValueDisplayFormat = '%.4f';
            app.YrEditFieldLabel = uilabel(app.LateralTab);
            app.YrEditFieldLabel.Text = 'Yr';
            app.YrEditField = uieditfield(app.LateralTab, 'numeric');
            app.YrEditField.ValueDisplayFormat = '%.4f';
            app.YpEditFieldLabel = uilabel(app.LateralTab);
            app.YpEditFieldLabel.Text = 'Yp';
            app.YpEditField = uieditfield(app.LateralTab, 'numeric');
            app.YpEditField.ValueDisplayFormat = '%.4f';
            app.YdaEditFieldLabel = uilabel(app.LateralTab);
            app.YdaEditFieldLabel.Text = 'Yda';
            app.YdaEditField = uieditfield(app.LateralTab, 'numeric');
            app.YdaEditField.ValueDisplayFormat = '%.4f';
            app.YdrEditFieldLabel = uilabel(app.LateralTab);
            app.YdrEditFieldLabel.Text = 'Ydr';
            app.YdrEditField = uieditfield(app.LateralTab, 'numeric');
            app.YdrEditField.ValueDisplayFormat = '%.4f';
            app.NbEditFieldLabel = uilabel(app.LateralTab);
            app.NbEditFieldLabel.Text = 'Nb';
            app.NbEditField = uieditfield(app.LateralTab, 'numeric');
            app.NbEditField.ValueDisplayFormat = '%.4f';
            app.NrEditFieldLabel = uilabel(app.LateralTab);
            app.NrEditFieldLabel.Text = 'Nr';
            app.NrEditField = uieditfield(app.LateralTab, 'numeric');
            app.NrEditField.ValueDisplayFormat = '%.4f';
            app.NpEditFieldLabel = uilabel(app.LateralTab);
            app.NpEditFieldLabel.Text = 'Np';
            app.NpEditField = uieditfield(app.LateralTab, 'numeric');
            app.NpEditField.ValueDisplayFormat = '%.4f';
            app.NdaEditFieldLabel = uilabel(app.LateralTab);
            app.NdaEditFieldLabel.Text = 'Nda';
            app.NdaEditField = uieditfield(app.LateralTab, 'numeric');
            app.NdaEditField.ValueDisplayFormat = '%.4f';
            app.NdrEditFieldLabel = uilabel(app.LateralTab);
            app.NdrEditFieldLabel.Text = 'Ndr';
            app.NdrEditField = uieditfield(app.LateralTab, 'numeric');
            app.NdrEditField.ValueDisplayFormat = '%.4f';
            app.LbEditFieldLabel = uilabel(app.LateralTab);
            app.LbEditFieldLabel.Text = 'Lb';
            app.LbEditField = uieditfield(app.LateralTab, 'numeric');
            app.LbEditField.ValueDisplayFormat = '%.4f';
            app.LrEditFieldLabel = uilabel(app.LateralTab);
            app.LrEditFieldLabel.Text = 'Lr';
            app.LrEditField = uieditfield(app.LateralTab, 'numeric');
            app.LrEditField.ValueDisplayFormat = '%.4f';
            app.LpEditFieldLabel = uilabel(app.LateralTab);
            app.LpEditFieldLabel.Text = 'Lp';
            app.LpEditField = uieditfield(app.LateralTab, 'numeric');
            app.LpEditField.ValueDisplayFormat = '%.4f';
            app.LdaEditFieldLabel = uilabel(app.LateralTab);
            app.LdaEditFieldLabel.Text = 'Lda';
            app.LdaEditField = uieditfield(app.LateralTab, 'numeric');
            app.LdaEditField.ValueDisplayFormat = '%.4f';
            app.LdrEditFieldLabel = uilabel(app.LateralTab);
            app.LdrEditFieldLabel.Text = 'Ldr';
            app.LdrEditField = uieditfield(app.LateralTab, 'numeric');
            app.LdrEditField.ValueDisplayFormat = '%.4f';
            % Show the figure after all components are created
            app.FlightDynamicsNondimensionalcoefficientconverterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DesignProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FlightDynamicsNondimensionalcoefficientconverterUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FlightDynamicsNondimensionalcoefficientconverterUIFigure)
        end
    end
end